classdef Calibration_LAICPMS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        XMapToolsCalibrationLaICPMS     matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        Image                           matlab.ui.control.Image
        GridLayout2                     matlab.ui.container.GridLayout
        Button_Help                     matlab.ui.control.Button
        GridLayout3                     matlab.ui.container.GridLayout
        AppyCalibration                 matlab.ui.control.Button
        GridLayout4                     matlab.ui.container.GridLayout
        MineralDropDownLabel            matlab.ui.control.Label
        MineralDropDown                 matlab.ui.control.DropDown
        InternalStandardDropDownLabel   matlab.ui.control.Label
        InternalStandardDropDown        matlab.ui.control.DropDown
        FixedcompositionCheckBox        matlab.ui.control.CheckBox
        VariablecompositionCheckBox     matlab.ui.control.CheckBox
        FixedInternalStdConc_EditFieldLabel  matlab.ui.control.Label
        FixedInternalStdConc_EditField  matlab.ui.control.NumericEditField
        InternalCompositionConverterLabel  matlab.ui.control.Label
        wtOxideDropDownLabel            matlab.ui.control.Label
        wtOxideDropDown                 matlab.ui.control.DropDown
        Oxide_EditField                 matlab.ui.control.NumericEditField
        ppm_EditField                   matlab.ui.control.NumericEditField
        toXXLabel                       matlab.ui.control.Label
        ggLabel                         matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        ElementDropDownLabel            matlab.ui.control.Label
        ElementDropDown                 matlab.ui.control.DropDown
        DisplayValue                    matlab.ui.control.NumericEditField
        ButtonTarget                    matlab.ui.control.Button
        Plot1                           matlab.ui.control.UIAxes
        Plot2                           matlab.ui.control.UIAxes
        Plot3                           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        XMapToolsApp        % Description
        MaskFile
        
        ElNamesStd
        NbElStd
        
        MapStandardsData
        MapCpsData
        
        Map_k
        
        ROI_sampling
        ROI_sampling_Listener
        
        DataInter
        NbPoints
        PointsValid
    end
    
    methods (Access = private)
        
        function PlotAllMaps(app)
            
            % Mask
            MaskId = app.MineralDropDown.Value;
            
            MaskMap = zeros(size(app.MaskFile.MaskMap));
            MaskInd = find(app.MaskFile.MaskMap == MaskId);
            MaskMap(MaskInd) = ones(size(MaskInd));
            
            % Intensity
            ElId = app.ElementDropDown.Value;
            CpsMap = app.MapCpsData(ElId).Cps;
            
            imagesc(app.Plot1,CpsMap.*MaskMap)
            axis(app.Plot1,'image')
            colorbar(app.Plot1);
            colormap(app.Plot1,app.XMapToolsApp.ColorMapValues_noMask);
            title(app.Plot1,[char(app.ElNamesStd{ElId}),' intensity (in cps)'])
            
            tb = axtoolbar(app.Plot1,{'pan','zoomin','zoomout','restoreview'});
            
            drawnow
            
            WePlot = 0;
            if isequal(app.FixedcompositionCheckBox.Value,1) 
                RefConc = app.FixedInternalStdConc_EditField.Value;
                if ~isequal(RefConc,0)
                    WePlot = 1;
                end
            end
            
            if isequal(app.VariablecompositionCheckBox.Value,1) 
                if sum(app.PointsValid) >= 2
                    WePlot = 1;
                end
            end
            
            
            if WePlot
                imagesc(app.Plot3,app.Map_k)
                axis(app.Plot3,'image')
                colorbar(app.Plot3);
                colormap(app.Plot3,app.XMapToolsApp.ColorMapValues_noMask);
                title(app.Plot3,['k matrix'])
                
                imagesc(app.Plot2,app.MapCpsData(ElId).Conc)
                axis(app.Plot2,'image')
                colorbar(app.Plot2);
                colormap(app.Plot2,app.XMapToolsApp.ColorMapValues_noMask);
                title(app.Plot2,[char(app.ElNamesStd{ElId}),' (in µg/g)'])
                
                app.Plot2.Visible = 'on';
                app.Plot3.Visible = 'on';
            else
                app.AppyCalibration.Enable = 'off';
                cla(app.Plot2)
                cla(app.Plot3)
                app.Plot2.Visible = 'off';
                app.Plot3.Visible = 'off';
            end
           
            
            %keyboard
            
        end
        
        function CalculateConversion(app)
            
            OxIdx = app.wtOxideDropDown.Value;
            OxValue = app.Oxide_EditField.Value;
            
            app.toXXLabel.Text = ['to ',app.XMapToolsApp.ElOxDataDef.ElList{app.XMapToolsApp.ElOxDataDef.OxElIdx(OxIdx)}];
            
            app.ppm_EditField.Value = OxValue * app.XMapToolsApp.ElOxDataDef.OxFact(OxIdx) * 1e4;
            
        end
        
        function CalibrateMaps(app)
            
            if isequal(app.FixedcompositionCheckBox.Value,1)
                RefConc = app.FixedInternalStdConc_EditField.Value;
                if ~isequal(RefConc,0)
                    % Mask
                    MaskId = app.MineralDropDown.Value;
                    
                    MaskMap = zeros(size(app.MaskFile.MaskMap));
                    MaskInd = find(app.MaskFile.MaskMap == MaskId);
                    MaskMap(MaskInd) = ones(size(MaskInd));
                    
                    %
                    IntStd_Id = app.InternalStandardDropDown.Value;
                    
                    It_Std = app.MapStandardsData(IntStd_Id).Cps .* MaskMap;
                    Con_Std = app.MapStandardsData(IntStd_Id).Conc .* MaskMap;
                    
                    It_Unk = app.MapCpsData(IntStd_Id).Cps .* MaskMap;
                    
                    if isequal(app.FixedcompositionCheckBox.Value,1)
                        Con_Unk = ones(size(MaskMap)).* app.FixedInternalStdConc_EditField.Value .* MaskMap;
                    end
                    
                    k_mtx = (It_Unk.*Con_Std)./(Con_Unk.*It_Std);
                    
                    for i = 1:length(app.InternalStandardDropDown.Items)
                        It_Unk_mtx = app.MapCpsData(i).Cps;
                        
                        It_Std_mtx = app.MapStandardsData(i).Cps;
                        Con_Std_mxt = app.MapStandardsData(i).Conc;
                        
                        app.MapCpsData(i).Conc = (It_Unk_mtx.*Con_Std_mxt)./(k_mtx.*It_Std_mtx);
                        
                        % Limit of detection (Theoule-sur-Mer)
                        Si = k_mtx.*It_Std_mtx./Con_Std_mxt;                % cps/µg.g-1
                        Bi = app.MapStandardsData(i).Int_Back;              % cps
                        DTi = 0.030;                                        % seconds                                           
                        NbAnal = app.MapStandardsData(i).Sweeps_Pixel;      % dimensionless
                        NbBack = app.MapStandardsData(i).Sweeps_Back;       % dimensionless
                        
                        app.MapCpsData(i).LOD = (3.29.*sqrt(Bi.*DTi.*NbAnal.*(1+NbAnal./NbBack)+2.71))./(NbAnal.*DTi.*Si);    % µg.g-1  
                        
                        %Con_Unk_mtx = (It_Unk_mtx.*Con_Std_mxt)./(k_mtx.*It_Std_mtx);
                        
                    end
                    
                    app.Map_k =  k_mtx;
                    
                    app.AppyCalibration.Enable = 'on';
                end
            else
                if sum(app.PointsValid) >= 2
                    % Mask
                    MaskId = app.MineralDropDown.Value;
                    
                    MaskMap = zeros(size(app.MaskFile.MaskMap));
                    MaskInd = find(app.MaskFile.MaskMap == MaskId);
                    MaskMap(MaskInd) = ones(size(MaskInd));
                    
                    %
                    IntStd_Id = app.InternalStandardDropDown.Value;
                    
                    It_Std = app.MapStandardsData(IntStd_Id).Cps .* MaskMap;
                    Con_Std = app.MapStandardsData(IntStd_Id).Conc .* MaskMap;
                    
                    It_Unk = app.MapCpsData(IntStd_Id).Cps .* MaskMap;
                    
                    % 
                    FitData = app.DataInter(:,find(app.PointsValid));
                    p = polyfit(FitData(1,:),FitData(2,:),1);
                    
                    Con_Unk = polyval(p,It_Unk) .* MaskMap;
                    
                    k_mtx = (It_Unk.*Con_Std)./(Con_Unk.*It_Std);
                    
                    for i = 1:length(app.InternalStandardDropDown.Items)
                        It_Unk_mtx = app.MapCpsData(i).Cps;
                        
                        It_Std_mtx = app.MapStandardsData(i).Cps;
                        Con_Std_mxt = app.MapStandardsData(i).Conc;
                        
                        app.MapCpsData(i).Conc = (It_Unk_mtx.*Con_Std_mxt)./(k_mtx.*It_Std_mtx);
                        
                        %Con_Unk_mtx = (It_Unk_mtx.*Con_Std_mxt)./(k_mtx.*It_Std_mtx);
                    end
                    
                    app.Map_k =  k_mtx;
                    
                    app.AppyCalibration.Enable = 'on';
                end
            end
            
            
        end
        
        function ChangeMode(app,Mode)
            
            switch Mode
                
                case 'Fixed'
                    app.FixedcompositionCheckBox.Value = 1;
                    app.VariablecompositionCheckBox.Value = 0;
                    
                    app.FixedInternalStdConc_EditField.Visible = 'on';
                    app.FixedInternalStdConc_EditFieldLabel.Visible = 'on';
                    
                    app.UITable.Visible = 'off';
                    app.DisplayValue.Visible = 'off';
                    app.ButtonTarget.Visible = 'off';
                    
                    %keyboard
                    
                case 'Variable'
                    app.FixedcompositionCheckBox.Value = 0;
                    app.VariablecompositionCheckBox.Value = 1;
                    
                    
                    app.FixedInternalStdConc_EditField.Visible = 'off';
                    app.FixedInternalStdConc_EditFieldLabel.Visible = 'off';
                    
                    app.UITable.Visible = 'on';
                    app.DisplayValue.Visible = 'on';
                    app.ButtonTarget.Visible = 'on';
                    
                    %keyboard
                    
            end
            
        end
        
        function ROI_Value_Extractor(app, ~)
            
            ImageData = PlotMap_ExtractPlottedImage(app);
            mask = createMask(app.ROI_sampling);
            Ind = find(mask);
            app.DisplayValue.Value = mean(ImageData(Ind));
            
        end
        
        function ImageData = PlotMap_ExtractPlottedImage(app)
            lesInd = app.Plot1.Children;
            for i=1:numel(lesInd)
                if isequal(lesInd(i).Type,'image')
                    ImageData = lesInd(i).CData;
                    break
                else
                    ImageData = [];
                end
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, XMapToolsApp, SelectedMaskFile)
            app.XMapToolsCalibrationLaICPMS.Visible = 'off';
            
            app.XMapToolsApp = XMapToolsApp;
            
            
            app.MaskFile.Name = app.XMapToolsApp.XMapToolsData.MapData.MaskFile.Names{SelectedMaskFile};
            app.MaskFile.NbMasks = app.XMapToolsApp.XMapToolsData.MapData.MaskFile.NbMasks(SelectedMaskFile);
            app.MaskFile.Names = app.XMapToolsApp.XMapToolsData.MapData.MaskFile.Masks(SelectedMaskFile).Names;
            app.MaskFile.MaskMap = app.XMapToolsApp.XMapToolsData.MapData.MaskFile.Masks(SelectedMaskFile).MaskMap;
            
            app.MineralDropDown.Items = app.MaskFile.Names(2:end);
            app.MineralDropDown.ItemsData = 1:app.MaskFile.NbMasks;
            
            % Standard data
            Names = app.XMapToolsApp.XMapToolsData.MapStandards.Names;
            Types = app.XMapToolsApp.XMapToolsData.MapStandards.Types;
            
            app.ElNamesStd = Names(find(Types == 1));
            app.NbElStd = length(app.ElNamesStd);
            
             % Data types:
            %       - Type 1        standard maps (Std_Map_Cps)
            %       - Type 2        composition of the standard (Std_Map_Conc)
            %       - Type 3        intensity of background (Int_Back)
            %       - Type 4        number of sweeps for background measurement (Sweeps_Back)
            %       - Type 5        number of sweeps for pixel measurement (Sweeps_Pixel)
            
            for i = 1:app.NbElStd
                app.MapStandardsData(i).Cps = app.XMapToolsApp.XMapToolsData.MapStandards.Data(i).Map;
                app.MapStandardsData(i).Int_Back = app.XMapToolsApp.XMapToolsData.MapStandards.Data(app.NbElStd+i).Map;
                app.MapStandardsData(i).Conc = app.XMapToolsApp.XMapToolsData.MapStandards.Data(app.NbElStd*2+i).Map;
                app.MapStandardsData(i).Sweeps_Back = app.XMapToolsApp.XMapToolsData.MapStandards.Data(app.NbElStd*3+i).Map;
                app.MapStandardsData(i).Sweeps_Pixel = app.XMapToolsApp.XMapToolsData.MapStandards.Data(app.NbElStd*4+i).Map;
            end
            
            app.InternalStandardDropDown.Items = app.ElNamesStd;
            app.InternalStandardDropDown.ItemsData = 1:app.NbElStd;
            
            app.ElementDropDown.Items = app.ElNamesStd;
            app.ElementDropDown.ItemsData = 1:app.NbElStd;
            
            % MapData
            ElNamesCps = app.XMapToolsApp.XMapToolsData.MapData.It.Names;
            [ElIsInStd,ElIndex] = ismember(app.ElNamesStd,ElNamesCps);
            
            if ~isequal(sum(ElIsInStd),length(ElIsInStd))
                
                Where0 = find(ElIsInStd == 0);
                Str = 'Following map(s) not available:';
                for i = 1:length(Where0)
                    Str = [Str,' ',char(app.ElNamesStd(Where0(i)))];
                end
                
                errordlg({'Mismatch between intensity maps and maps of standards',Str},'Error')
                close(app.XMapToolsCalibrationLaICPMS)
                return
            end
            
            for i = 1:app.NbElStd
                app.MapCpsData(i).Cps = app.XMapToolsApp.XMapToolsData.MapData.It.Data(ElIndex(i)).Map;
                app.MapCpsData(i).Conc = zeros(size(app.MapCpsData(i).Cps));
                app.MapCpsData(i).LOD = zeros(size(app.MapCpsData(i).Cps));
            end
            
            app.UITable.Data = {0,0,0,0,0,0;0,0,0,0,0,0};
            
            app.FixedcompositionCheckBox.Value = 1;
            app.UITable.Visible = 'Off';
            app.DisplayValue.Visible = 'off';
            app.ButtonTarget.Visible = 'off';
            
            
            app.wtOxideDropDown.Items = app.XMapToolsApp.ElOxDataDef.OxList;
            app.wtOxideDropDown.ItemsData = 1:length(app.wtOxideDropDown.Items);
            CalculateConversion(app);
            
            %keyboard
            
            
            PlotAllMaps(app);
            
            movegui(app.XMapToolsCalibrationLaICPMS,'center');
            
            app.XMapToolsCalibrationLaICPMS.Visible = 'on';
        end

        % Value changed function: InternalStandardDropDown
        function InternalStandardDropDownValueChanged(app, event)
            app.ElementDropDown.Value = app.InternalStandardDropDown.Value;
            
            % Update oxides
            ElList_DB = app.XMapToolsApp.ElOxDataDef.ElList;
            [Ok,ElIdx] = ismember(char(app.ElementDropDown.Items(app.ElementDropDown.Value)),ElList_DB);
            OxId = find(app.XMapToolsApp.ElOxDataDef.OxElIdx == ElIdx);
            if ~isempty(OxId)
                app.wtOxideDropDown.Value = OxId(1);
            end
            
            CalculateConversion(app);
            
            CalibrateMaps(app);
            PlotAllMaps(app);
        end

        % Value changed function: MineralDropDown
        function MineralDropDownValueChanged(app, event)
            delete(findall(app.Plot1, 'Type',  'images.roi.Circle'));
            CalibrateMaps(app);
            PlotAllMaps(app);
        end

        % Value changed function: ElementDropDown
        function ElementDropDownValueChanged(app, event)
            delete(findall(app.Plot1, 'Type',  'images.roi.Circle'));
            PlotAllMaps(app);
        end

        % Value changed function: FixedInternalStdConc_EditField
        function FixedInternalStdConc_EditFieldValueChanged(app, event)
            CalibrateMaps(app);
            PlotAllMaps(app);
        end

        % Value changed function: wtOxideDropDown
        function wtOxideDropDownValueChanged(app, event)
            CalculateConversion(app);
        end

        % Value changed function: Oxide_EditField
        function Oxide_EditFieldValueChanged(app, event)
            CalculateConversion(app);
            if app.FixedcompositionCheckBox.Value
                app.FixedInternalStdConc_EditField.Value = app.ppm_EditField.Value;
                FixedInternalStdConc_EditFieldValueChanged(app, event)
            end
        end

        % Button pushed function: AppyCalibration
        function AppyCalibrationButtonPushed(app, event)
            
            Pos = length(app.XMapToolsApp.XMapToolsData.MapData.Qt.Names) + 1;
            
            app.XMapToolsApp.XMapToolsData.MapData.Qt.Names{Pos} = [char(app.MineralDropDown.Items{app.MineralDropDown.Value}),' µg/g'];
            app.XMapToolsApp.XMapToolsData.MapData.Qt.IsOxide(Pos) = 0;
            app.XMapToolsApp.XMapToolsData.MapData.Qt.MaskFile{Pos} = app.MaskFile.Name;
            app.XMapToolsApp.XMapToolsData.MapData.Qt.NbCalibPoints(Pos) = 0;
            
            for i = 1:length(app.ElNamesStd)
                app.XMapToolsApp.XMapToolsData.MapData.Qt.Data(Pos).ElNames{i} = app.ElNamesStd{i};
                app.XMapToolsApp.XMapToolsData.MapData.Qt.Data(Pos).ElInd(i) = find(ismember(app.XMapToolsApp.ElOxDataDef.ElList,app.ElNamesStd{i}));
                app.XMapToolsApp.XMapToolsData.MapData.Qt.Data(Pos).CData(i).Map = app.MapCpsData(i).Conc;
            end
            
            % Limit of detection:
            Pos = length(app.XMapToolsApp.XMapToolsData.MapData.Ot.Names);
            
            for i = 1:length(app.ElNamesStd)
                app.XMapToolsApp.XMapToolsData.MapData.Ot.Names{Pos+i} = [char(app.ElNamesStd{i}),'_',char(app.MineralDropDown.Items{app.MineralDropDown.Value}),' µg/g'];
                app.XMapToolsApp.XMapToolsData.MapData.Ot.Types(Pos+i) = 1;
                app.XMapToolsApp.XMapToolsData.MapData.Ot.Data(Pos+i).Map = app.MapCpsData(i).LOD;
            end
            
            close(app.XMapToolsCalibrationLaICPMS); 
            
        end

        % Value changed function: FixedcompositionCheckBox
        function FixedcompositionCheckBoxValueChanged(app, event)
            value = app.FixedcompositionCheckBox.Value;
            if isequal(value,1) && isequal(app.VariablecompositionCheckBox.Value,1)
                % We go to this mode
                ChangeMode(app,'Fixed');
            else
                ChangeMode(app,'Variable'); 
            end
        end

        % Value changed function: VariablecompositionCheckBox
        function VariablecompositionCheckBoxValueChanged(app, event)
            value = app.VariablecompositionCheckBox.Value;
            if isequal(value,1) && isequal(app.FixedcompositionCheckBox.Value,1)
                % We go to this mode
                ChangeMode(app,'Variable');
            else
                ChangeMode(app,'Fixed');
            end
        end

        % Button pushed function: ButtonTarget
        function ButtonTargetPushed(app, event)
            
            delete(findall(app.Plot1, 'Type',  'images.roi.Circle'));
            
            app.ROI_sampling = drawcircle(app.Plot1,'Color',[0.47,0.67,0.19],'InteractionsAllowed','all');
            
            app.ROI_sampling_Listener = addlistener(app.ROI_sampling, 'ROIMoved', @(varargin)ROI_Value_Extractor(app, app.ROI_sampling));
            
            ROI_Value_Extractor(app, app.ROI_sampling);
            
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
            NewDisplayData = app.UITable.DisplayData;
            
            app.DataInter = cell2mat(NewDisplayData);
            
            app.NbPoints = 0;
            app.PointsValid = zeros(1,size(app.DataInter,2));
            for i = 1:size(app.DataInter,2)
                if app.DataInter(1,i) > 0 && app.DataInter(2,i) > 0
                    app.NbPoints = app.NbPoints+1;
                    app.PointsValid(i) = 1;
                end
            end
            
            if sum(app.PointsValid) >= 2
                CalibrateMaps(app);
                PlotAllMaps(app);
            end
            
            
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            
            if isequal(indices(1),1)
                app.UITable.Data(indices(1),indices(2)) = {app.DisplayValue.Value};
            end
            
            if isequal(indices(1),2)
                app.UITable.Data(indices(1),indices(2)) = {app.ppm_EditField.Value};
            end
            
            UITableDisplayDataChanged(app, event)
        end

        % Button pushed function: Button_Help
        function Button_HelpPushed(app, event)
            
            
            if isempty(app.XMapToolsApp.Id_HelpTool)
                Help_Display(app.XMapToolsApp,'XMT_help_Calibration_LAICPMS.html');
            else
                app.XMapToolsApp.Id_HelpTool.UpdateTextHelp('XMT_help_Calibration_LAICPMS.html');
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.XMapToolsCalibrationLaICPMS = uifigure('Visible', 'off');
            app.XMapToolsCalibrationLaICPMS.Name = 'Calibration Assistant For LA-ICP-MS Data – XMapTools';
            app.GridLayout = uigridlayout(app.XMapToolsCalibrationLaICPMS);
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = 'logo_xmap_final.png';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.Button_Help = uibutton(app.GridLayout2, 'push');
            app.Button_Help.ButtonPushedFcn = createCallbackFcn(app, @Button_HelpPushed, true);
            app.Button_Help.Icon = '061-info.png';
            app.Button_Help.Text = '';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.AppyCalibration = uibutton(app.GridLayout3, 'push');
            app.AppyCalibration.ButtonPushedFcn = createCallbackFcn(app, @AppyCalibrationButtonPushed, true);
            app.AppyCalibration.Icon = '044-repeat.png';
            app.AppyCalibration.Enable = 'off';
            app.AppyCalibration.Text = 'Apply standardization';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.MineralDropDownLabel = uilabel(app.GridLayout4);
            app.MineralDropDownLabel.Text = 'Mineral';
            app.MineralDropDown = uidropdown(app.GridLayout4);
            app.MineralDropDown.ValueChangedFcn = createCallbackFcn(app, @MineralDropDownValueChanged, true);
            app.InternalStandardDropDownLabel = uilabel(app.GridLayout4);
            app.InternalStandardDropDownLabel.Text = 'Internal Standard';
            app.InternalStandardDropDown = uidropdown(app.GridLayout4);
            app.InternalStandardDropDown.ValueChangedFcn = createCallbackFcn(app, @InternalStandardDropDownValueChanged, true);
            app.FixedcompositionCheckBox = uicheckbox(app.GridLayout4);
            app.FixedcompositionCheckBox.ValueChangedFcn = createCallbackFcn(app, @FixedcompositionCheckBoxValueChanged, true);
            app.FixedcompositionCheckBox.Text = 'Fixed composition';
            app.VariablecompositionCheckBox = uicheckbox(app.GridLayout4);
            app.VariablecompositionCheckBox.ValueChangedFcn = createCallbackFcn(app, @VariablecompositionCheckBoxValueChanged, true);
            app.VariablecompositionCheckBox.Text = 'Variable composition';
            app.FixedInternalStdConc_EditFieldLabel = uilabel(app.GridLayout4);
            app.FixedInternalStdConc_EditFieldLabel.Text = 'µg/g';
            app.FixedInternalStdConc_EditField = uieditfield(app.GridLayout4, 'numeric');
            app.FixedInternalStdConc_EditField.ValueChangedFcn = createCallbackFcn(app, @FixedInternalStdConc_EditFieldValueChanged, true);
            app.InternalCompositionConverterLabel = uilabel(app.GridLayout4);
            app.InternalCompositionConverterLabel.Text = 'Internal Composition Converter';
            app.wtOxideDropDownLabel = uilabel(app.GridLayout4);
            app.wtOxideDropDownLabel.Text = 'wt% Oxide';
            app.wtOxideDropDown = uidropdown(app.GridLayout4);
            app.wtOxideDropDown.ValueChangedFcn = createCallbackFcn(app, @wtOxideDropDownValueChanged, true);
            app.Oxide_EditField = uieditfield(app.GridLayout4, 'numeric');
            app.Oxide_EditField.ValueChangedFcn = createCallbackFcn(app, @Oxide_EditFieldValueChanged, true);
            app.Oxide_EditField.Value = 1;
            app.ppm_EditField = uieditfield(app.GridLayout4, 'numeric');
            app.ppm_EditField.Editable = 'off';
            app.toXXLabel = uilabel(app.GridLayout4);
            app.toXXLabel.Text = 'to XX';
            app.ggLabel = uilabel(app.GridLayout4);
            app.ggLabel.Text = 'µg/g';
            app.UITable = uitable(app.GridLayout4);
            app.UITable.ColumnName = {'Pt-1'; 'Pt-2'; 'Pt-3'; 'Pt-4'; 'Pt-5'; 'Pt-6'};
            app.UITable.RowName = {'Intensity (cps)'; 'Composition (µg/g)'; ''};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.ElementDropDownLabel = uilabel(app.GridLayout4);
            app.ElementDropDownLabel.Text = 'Element';
            app.ElementDropDown = uidropdown(app.GridLayout4);
            app.ElementDropDown.ValueChangedFcn = createCallbackFcn(app, @ElementDropDownValueChanged, true);
            app.DisplayValue = uieditfield(app.GridLayout4, 'numeric');
            app.DisplayValue.Editable = 'off';
            app.ButtonTarget = uibutton(app.GridLayout4, 'push');
            app.ButtonTarget.ButtonPushedFcn = createCallbackFcn(app, @ButtonTargetPushed, true);
            app.ButtonTarget.Icon = '142-target.png';
            app.ButtonTarget.Tooltip = {'Pick a ROI (circle)'};
            app.ButtonTarget.Text = '';
            app.Plot1 = uiaxes(app.GridLayout);
            title(app.Plot1, 'Title')
            app.Plot1.XTick = [];
            app.Plot1.YTick = [];
            app.Plot2 = uiaxes(app.GridLayout);
            title(app.Plot2, 'Title')
            app.Plot2.XTick = [];
            app.Plot2.YTick = [];
            app.Plot3 = uiaxes(app.GridLayout);
            title(app.Plot3, 'Title')
            app.Plot3.XTick = [];
            app.Plot3.YTick = [];
            % Show the figure after all components are created
            app.XMapToolsCalibrationLaICPMS.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Calibration_LAICPMS(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.XMapToolsCalibrationLaICPMS)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.XMapToolsCalibrationLaICPMS)
        end
    end
end