classdef Converter_LAICPMS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ConverterLAICPMS                matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        Image                           matlab.ui.control.Image
        Tree                            matlab.ui.container.Tree
        Node_Background                 matlab.ui.container.TreeNode
        Node_PrimaryStandard            matlab.ui.container.TreeNode
        Node_SecondaryStandard          matlab.ui.container.TreeNode
        Node_Scans                      matlab.ui.container.TreeNode
        GridLayout2                     matlab.ui.container.GridLayout
        LoadDatafilesButton             matlab.ui.control.Button
        GridLayout4                     matlab.ui.container.GridLayout
        HelpButton                      matlab.ui.control.Button
        TimeshiftSpinnerLabel           matlab.ui.control.Label
        TimeshiftSpinner                matlab.ui.control.Spinner
        GridLayout3                     matlab.ui.container.GridLayout
        PlotDropDownLabel               matlab.ui.control.Label
        PlotDropDown                    matlab.ui.control.DropDown
        BACKGROUNDPanel                 matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        ExtractIntegrationsBackgroundButton  matlab.ui.control.Button
        BackgroundFilterPercentSpinnerLabel  matlab.ui.control.Label
        BackgroundFilterPercentSpinner  matlab.ui.control.Spinner
        Background_CorrectionList       matlab.ui.control.DropDown
        BackgroundApplyButton           matlab.ui.control.Button
        PRIMARYSTANDARDPanel            matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        PrimaryStd_FilterPercentSpinnerLabel  matlab.ui.control.Label
        PrimaryStd_FilterPercentSpinner  matlab.ui.control.Spinner
        PrimaryStd_List                 matlab.ui.control.DropDown
        PrimaryStd_ApplyButton          matlab.ui.control.Button
        PrimaryStd_CorrectionList       matlab.ui.control.DropDown
        SECONDARYSTANDARDPanel          matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        SecondaryStd_List               matlab.ui.control.DropDown
        SecondaryStd_FilterPercentSpinnerLabel  matlab.ui.control.Label
        SecondaryStd_FilterPercentSpinner  matlab.ui.control.Spinner
        SecondaryStd_UITable            matlab.ui.control.Table
        SecondaryStd_IntStdLabel        matlab.ui.control.Label
        SecondaryStd_IntStd             matlab.ui.control.DropDown
        SecondaryStd_ApplyButton        matlab.ui.control.Button
        MAPSPanel                       matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        Maps_ApplyButton                matlab.ui.control.Button
        Maps_TimeshiftSpinnerLabel      matlab.ui.control.Label
        Maps_TimeshiftSpinner           matlab.ui.control.Spinner
        Maps_List                       matlab.ui.control.DropDown
        CheckButton                     matlab.ui.control.Button
        ShowButton                      matlab.ui.control.Button
        ExportButton                    matlab.ui.control.Button
        Maps_SelElement                 matlab.ui.control.DropDown
        GridLayout9                     matlab.ui.container.GridLayout
        PositionLabel                   matlab.ui.control.Label
        Position_Min                    matlab.ui.control.Spinner
        Position_Max                    matlab.ui.control.Spinner
        IntegrationMenuLabel            matlab.ui.control.Label
        Plot                            matlab.ui.control.UIAxes
        ContextMenu                     matlab.ui.container.ContextMenu
        CopyMenu                        matlab.ui.container.Menu
    end

    
    properties (Access = private)
        XMapToolsApp
        LastDir         % from XMapTools (startup)
        Data            % Data
        Log             % Data from the log file
        TimeShiftCorrData   % Data from the automated time shift correction
        Integrations
        Data4Plot       % Contain all data to be plotted in PlotDropDown
        Start_Idx_Data4Plot_BackgroundCorrected % Description
        RefData_LAICPMS % Description
        PathStdFolder   % Description
    end
    
    methods (Access = private)
        
        function ExtractTimeIntegration(app)
            
            t = app.Data.tsec;
            
            %List_Type = '';
            Type = zeros(size(t));
            SeqN = zeros(size(t));
            SeqListName = {};
            StartSeq = zeros(size(t));
            BackMeas = zeros(size(t));
            BackMeasID = zeros(size(t));
            SignalMeas = zeros(size(t));
            SignalMeasTotal = zeros(size(t));
            SignalMeasID = zeros(size(t));
            
            ComptScan = 0;
            
            
            app.Integrations.SeqListName = '';
            
            app.Integrations.Background.Names = {};
            app.Integrations.Background.PositionsOri = [];
            app.Integrations.Background.Positions = [];
            app.Integrations.Background.Times = NaT(1);
            %app.Integrations.Background.ROI(1).ROI = [];
            
            app.Integrations.TypeNames = '';
            app.Integrations.Measurements(1).Names = {};
            app.Integrations.Measurements(1).PositionsOri = [];
            app.Integrations.Measurements(1).Positions = [];
            app.Integrations.Measurements(1).Times = NaT(1);
            
            app.Integrations.Measurements(1).X1 = [];
            app.Integrations.Measurements(1).Y1 = [];
            app.Integrations.Measurements(1).X2 = [];
            app.Integrations.Measurements(1).Y2 = [];
            app.Integrations.Measurements(1).SpotSize = [];
            app.Integrations.Measurements(1).ScanVel = [];
            app.Integrations.Measurements(1).Distance = [];
            
            app.Integrations.Measurements(1).Slope = [];
            app.Integrations.Measurements(1).Intercept = [];
            
            
            %figure, hold on
            
            CountMeasurements = zeros(10);
            
            % Sequence
            SqIndex = find(~isnan(app.Log.Table.SequenceNumber));
            for i = 1:length(SqIndex)
                
                Id1 = SqIndex(i);
                t1 = app.Log.DT_Log_Corr(Id1);
                if i < length(SqIndex)
                    Id2 = SqIndex(i+1);
                    t2 = app.Log.DT_Log_Corr(Id2);
                else
                    Id2 = length(app.Log.Table.SequenceNumber);
                    t2 = app.Log.DT_Log_Corr(Id2);
                end
                
                tf = find(isbetween(app.Data.time_DT,t1,t2));
                SeqN(tf) = i*ones(size(tf));
                
                SeqLaserState = app.Log.Table.LaserState(Id1:Id2);
                IsLaserOn = find(ismember(SeqLaserState,'On'));
                
                StartSeq(tf(1)) = 1;
                for j = Id1:Id2
                    if isequal(app.Log.Table.LaserState{j},'On')
                        tback = find(isbetween(app.Data.time_DT,app.Log.DT_Log_Corr(SqIndex(i)),app.Log.DT_Log_Corr(j)));
                        % we filter 10%
                        %Filter = round(length(tback)*0.05);
                        %BackMeas(tback(Filter:end-Filter)) = ones(size(tback(Filter:end-Filter)));
                        %BackMeasID(tback(Filter:end-Filter)) = i*ones(size(tback(Filter:end-Filter)));
                        break
                    end
                end
                
                SeqName = app.Log.Table.Comment{SqIndex(i)}; SeqName = textscan(SeqName,'%s');  AnaID = str2num(SeqName{1}{3}); SeqName = SeqName{1}{1};
                
                IsSeq = find(ismember(app.Integrations.TypeNames,SeqName));
                if ~isempty(IsSeq)
                    Type(tf) = IsSeq*ones(size(tf));
                else
                    app.Integrations.TypeNames{end+1} = SeqName;
                    IsSeq = length(app.Integrations.TypeNames);
                    Type(tf) = IsSeq*ones(size(tf));
                end
                
                SeqListName{i} = SeqName;
                
                app.Integrations.Background.Names{i} = SeqName;
                app.Integrations.Background.PositionsOri(i,1:2) = [tback(1),tback(end)];
                app.Integrations.Background.Positions(i,1:2) = [tback(1),tback(end)];
                app.Integrations.Background.Times(i,1:2) = [app.Data.time_DT(tback(1)),app.Data.time_DT(tback(end))];
                
                % SIGNAL
                CountMeasurements(IsSeq) =  CountMeasurements(IsSeq) + 1;
                IdxCount = CountMeasurements(IsSeq);
                
                app.Integrations.Measurements(IsSeq).Names{IdxCount} = SeqName;
                
                tAblation = find(isbetween(app.Data.time_DT,app.Log.DT_Log_Corr(SqIndex(i)+IsLaserOn(end-1)-1),app.Log.DT_Log_Corr(SqIndex(i)+IsLaserOn(end-1))));

                app.Integrations.Measurements(IsSeq).PositionsOri(IdxCount,1:2) = [tAblation(1),tAblation(end)];
                app.Integrations.Measurements(IsSeq).Positions(IdxCount,1:2) = [tAblation(1),tAblation(end)];
                app.Integrations.Measurements(IsSeq).Times(IdxCount,1:2) = [app.Data.time_DT(tAblation(1)),app.Data.time_DT(tAblation(end))];
                
                % add later the coordinates and scan speed, etc...
                app.Integrations.Measurements(IsSeq).X1(CountMeasurements(IsSeq)) = app.Log.Table.X_um_(SqIndex(i)+IsLaserOn(end-1)-1);
                app.Integrations.Measurements(IsSeq).Y1(CountMeasurements(IsSeq)) = app.Log.Table.Y_um_(SqIndex(i)+IsLaserOn(end-1)-1);
                app.Integrations.Measurements(IsSeq).X2(CountMeasurements(IsSeq)) = app.Log.Table.X_um_(SqIndex(i)+IsLaserOn(end-1));
                app.Integrations.Measurements(IsSeq).Y2(CountMeasurements(IsSeq)) = app.Log.Table.Y_um_(SqIndex(i)+IsLaserOn(end-1));
                app.Integrations.Measurements(IsSeq).SpotSize(CountMeasurements(IsSeq)) = app.Log.Table.SpotSize_um_(SqIndex(i)+IsLaserOn(end-1)-1);
                app.Integrations.Measurements(IsSeq).ScanVel(CountMeasurements(IsSeq)) = app.Log.Table.ScanVelocity_um_s_(SqIndex(i)+IsLaserOn(end-1));


%                 tAblation = find(isbetween(app.Data.time_DT,app.Log.DT_Log_Corr(SqIndex(i)+IsLaserOn(end-1)-1),app.Log.DT_Log_Corr(SqIndex(i)+IsLaserOn(end-1))));
%                 Filter = round(length(tAblation)*0.05); % ok for Std not for maps
%                 SignalMeas(tAblation(Filter:end-Filter)) = IsSeq*ones(size(tAblation(Filter:end-Filter)));
%                 
%                 tAblationTotal = find(isbetween(app.Data.time_DT,app.Log.DT_Log_Corr(SqIndex(i)+IsLaserOn(end-1)-1),app.Log.DT_Log_Corr(SqIndex(i)+IsLaserOn(end-1))+seconds(5)));
%                 SignalMeasTotal(tAblationTotal) = IsSeq*ones(size(tAblationTotal(:)));
%                 SignalMeasID(tAblationTotal) = AnaID*ones(size(tAblationTotal(:)));
%                 
%                 if isequal(SeqName,'area')
%                     ComptScan=ComptScan+1;
%                     
%                     X1 = app.Log.Table.X_um_(SqIndex(i)+IsLaserOn(end-1)-1);
%                     Y1 = app.Log.Table.Y_um_(SqIndex(i)+IsLaserOn(end-1)-1);
%                     
%                     X2 = app.Log.Table.X_um_(SqIndex(i)+IsLaserOn(end-1));
%                     Y2 = app.Log.Table.Y_um_(SqIndex(i)+IsLaserOn(end-1));
%                     
%                     ScanData(ComptScan).Slope = (Y2-Y1)/(X2-X1);
%                     ScanData(ComptScan).Int = Y1-ScanData(ComptScan).Slope*X1;
%                     
%                     ScanData(ComptScan).Distance = sqrt((X2-X1)^2+(Y2-Y1)^2);
%                     
%                     ScanData(ComptScan).SpotSize = app.Log.Table.SpotSize_um_(SqIndex(i)+IsLaserOn(end-1)-1);
%                     ScanData(ComptScan).ScanVel = app.Log.Table.ScanVelocity_um_s_(SqIndex(i)+IsLaserOn(end-1));
%                     
%                     ScanData(ComptScan).DurationReal = ScanData(ComptScan).Distance/ScanData(ComptScan).ScanVel; % in seconds
%                     ScanData(ComptScan).NbPixels = floor(ScanData(ComptScan).Distance/ScanData(ComptScan).SpotSize);
%                     
%                     ScanData(ComptScan).Xi = X1:ScanData(ComptScan).SpotSize:X2;
%                     ScanData(ComptScan).Yi = ScanData(ComptScan).Slope*ScanData(ComptScan).Xi+ScanData(ComptScan).Int;
%                     
%                     ScanData(ComptScan).DistanceLine = sqrt((ScanData(ComptScan).Xi(end)-ScanData(ComptScan).Xi(1))^2+(ScanData(ComptScan).Yi(end)-ScanData(ComptScan).Yi(1))^2);
%                     ScanData(ComptScan).DurationLine = ScanData(ComptScan).DistanceLine/ScanData(ComptScan).ScanVel;
%                     
%                     %plot(ScanData(ComptScan).Xi,ScanData(ComptScan).Yi,'o')
%                     
%                     % Search indexes between: "start + Transport_t" ->  "same + duration of line scan"
%                     ScanData(ComptScan).PxIdx = zeros(size(t));
%                     
%                     StartTime = app.Log.DT_Log_Corr(SqIndex(i)+IsLaserOn(end-1)-1);
%                     TransportTime = seconds(1);
%                     
%                     DtPixel = seconds(ScanData(ComptScan).DurationLine/ScanData(ComptScan).NbPixels);
%                     
%                     for j = 1:ScanData(ComptScan).NbPixels
%                         tAblationPixel = find(isbetween(app.Data.time_DT,StartTime+TransportTime+(j-1)*DtPixel,StartTime+TransportTime+j*DtPixel));
%                         ScanData(ComptScan).PxIdx(tAblationPixel) = j*ones(size(tAblationPixel));
%                     end
%                 end
            end


            app.Integrations.SeqListName = SeqListName;
            
            UpdateTimeIntegration_Background(app);
            
            
            % UPDATE TREE
            CleanTree(app,'Background');
            for i = 1:length(SeqListName)
                p = uitreenode(app.Node_Background,'Text',char(SeqListName{i}),'NodeData',[1,i]);
            end
            expand(app.Node_Background);


            %keyboard
            
            
        end
        
        function Plot2CheckShift(app)
            
            hold(app.Plot,'off')
            plot(app.Plot,app.Data.time_DT,app.Data.SumData,'k');
            hold(app.Plot,'on')
            
            app.Plot.YScale = 'log';
            
            rp = rulerPanInteraction('Dimensions','x');
            app.Plot.Interactions = [rp];
            app.Plot.Visible = 'on';
            
            LaserOn = find(ismember(app.Log.Table.LaserState,'On'));
            TimeLaserOn = app.Log.DT_Log_Corr(LaserOn);
            plot(app.Plot,[TimeLaserOn,TimeLaserOn]',repmat([app.Plot.YLim(1),app.Plot.YLim(2)],length(TimeLaserOn),1)','r-');
            
        end
        
        function SearchShiftAuto(app)
            % Detection of the first peak in the derivative
            DiffSumData = diff(app.Data.SumData);
            Mean10 = mean(DiffSumData(1:15));
            Std10 = std(DiffSumData(1:15));
            
            for iPos = 2:length(DiffSumData)
                if DiffSumData(iPos)-DiffSumData(iPos-1) > Mean10+5*Std10
                    break
                end
            end
            AblationStartSignal = app.Data.time_DT(iPos+1);
            
            % Laser log
            LaserOn = find(ismember(app.Log.Table.LaserState,'On'));
            AblationStartLog = app.Log.Table.Timestamp(LaserOn(1));
            
            % Shift & Correction
            TimeShift = duration(AblationStartSignal-AblationStartLog);
            
            app.Log.DT_Log_Corr = app.Log.DT_Log + TimeShift;
            
            app.TimeshiftSpinner.Value = seconds(TimeShift);
            app.TimeshiftSpinner.Visible = 'on';
            app.TimeshiftSpinnerLabel.Visible = 'on';
            
        end
        
        function CleanTree(app,Tree)
            
            switch Tree
                case 'Background'
                    for i = 1:length(app.Node_Background.Children)
                        app.Node_Background.Children(1).delete;
                    end
                case 'Primary'
                    for i = 1:length(app.Node_PrimaryStandard.Children)
                        app.Node_PrimaryStandard.Children(1).delete;
                    end
                case 'Secondary'
                    for i = 1:length(app.Node_SecondaryStandard.Children)
                        app.Node_SecondaryStandard.Children(1).delete;
                    end
                case 'Scans'
                    for i = 1:length(app.Node_Scans.Children)
                        app.Node_Scans.Children(1).delete;
                    end  
            end
            
        end
        
        function UpdateTimeIntegration_Background(app)
            
            for i = 1:size(app.Integrations.Background.PositionsOri,1)
                
                Interval = [app.Integrations.Background.PositionsOri(i,1):1:app.Integrations.Background.PositionsOri(i,2)];
                Filter = round(length(Interval)*(app.BackgroundFilterPercentSpinner.Value/100));
                
                app.Integrations.Background.Positions(i,1) = app.Integrations.Background.PositionsOri(i,1)+Filter;
                app.Integrations.Background.Positions(i,2) = app.Integrations.Background.PositionsOri(i,2)-Filter;
                
                app.Integrations.Background.Times(i,1:2) = [app.Data.time_DT(app.Integrations.Background.Positions(i,1)),app.Data.time_DT(app.Integrations.Background.Positions(i,2))];
                
            end
            
        end
        
        function UpdateTimeIntegration_PrimaryStd(app)
            
            Value = app.PrimaryStd_List.Value;
            
            for i = 1:length(app.Integrations.Measurements(Value).Names)
                
                Interval = [app.Integrations.Measurements(Value).PositionsOri(i,1):1:app.Integrations.Measurements(Value).PositionsOri(i,2)];
                Filter = round(length(Interval)*(app.PrimaryStd_FilterPercentSpinner.Value/100));
                
                app.Integrations.Measurements(Value).Positions(i,1) = app.Integrations.Measurements(Value).PositionsOri(i,1)+Filter;
                app.Integrations.Measurements(Value).Positions(i,2) = app.Integrations.Measurements(Value).PositionsOri(i,2)-Filter;
                
                app.Integrations.Measurements(Value).Times(i,1:2) = [app.Data.time_DT(app.Integrations.Measurements(Value).Positions(i,1)),app.Data.time_DT(app.Integrations.Measurements(Value).Positions(i,2))];
            end
            
        end
        
        
        function ReadStandardData(app)
            
            app.RefData_LAICPMS.FileNames = '';
            app.RefData_LAICPMS.StdNames = '';
            app.RefData_LAICPMS.Data(1).Elem = '';
            app.RefData_LAICPMS.Data(1).Unit = '';
            app.RefData_LAICPMS.Data(1).Value = [];
            app.RefData_LAICPMS.Data(1).Unc = [];
            
            DIR = dir(app.PathStdFolder);
            
            ComptFile = 0;
            for i = 1:length(DIR)
                
                if ~isequal(DIR(i).name,'.') && ~isequal(DIR(i).name,'..')
                    ComptFile = ComptFile+1;
                    ComptStd = 0;
                    
                    app.RefData_LAICPMS.FileNames{ComptFile} = char(DIR(i).name);
                    app.RefData_LAICPMS.StdNames{ComptFile} = upper(char(DIR(i).name(1:end-4)));
                    
                    fid = fopen([app.PathStdFolder,'/',DIR(i).name],'r');
                    while 1
                        TheL = fgetl(fid);
                        
                        if isequal(TheL,-1)
                            break
                        end
                        
                        if length(TheL) > 1
                            if ~isequal(TheL(1),'!')
                                ComptStd = ComptStd+1;
                                
                                Out = textscan(TheL,'%s%s%f%s');
                                app.RefData_LAICPMS.Data(ComptFile).Elem{ComptStd} = char(Out{1});
                                app.RefData_LAICPMS.Data(ComptFile).Unit{ComptStd} = char(Out{2});
                                app.RefData_LAICPMS.Data(ComptFile).Value(ComptStd) = Out{3};
                                if isequal(char(Out{4}),'-')
                                    app.RefData_LAICPMS.Data(ComptFile).Unc(ComptStd) = 0;
                                else
                                    app.RefData_LAICPMS.Data(ComptFile).Unc(ComptStd) = str2num(char(Out{4}));
                                end
                                
                            end
                        end
                    end
                    fclose(fid);
                    
                end
            end


        end
        
        function PrepareSecondaryStandardData(app)
            
            SecStd = app.SecondaryStd_List.Items{app.SecondaryStd_List.Value};
            
            [IsStd] = ismember(app.RefData_LAICPMS.StdNames,upper(SecStd));
            
            if ~isequal(sum(IsStd),1)
                errordlg('Standart not found, Pierre needs to work more here...','XMapTools');
                % Select the corresponding Std in the list...
                return
            end
            
            WhereStd = find(IsStd);
            
            ElListStd = app.RefData_LAICPMS.Data(WhereStd).Elem;
            ElAnalysis = app.Data.ElName;
            
            [IsElem,ElemIdx] = ismember(ElAnalysis,ElListStd);
            
            if ~isequal(sum(IsElem),length(IsElem))
                errordlg('Element in Standart not found, Pierre needs to work more here...','XMapTools');
                return
            end
            
            app.Data.SecondaryStandard_ElemConc = app.RefData_LAICPMS.Data(WhereStd).Value(ElemIdx);
            
            ElNames4Menu = '';
            for i = 1:length(app.Data.SecondaryStandard_ElemConc)
                ElNames4Menu{i} = [char(ElAnalysis{i}),' (',num2str(app.Data.SecondaryStandard_ElemConc(i)),' µg/g)'];
            end
            app.SecondaryStd_IntStd.Items = ElNames4Menu;
            app.SecondaryStd_IntStd.ItemsData = [1:length(ElNames4Menu)];
            
            Value = app.SecondaryStd_List.Value;
            
            % UPDATE TREE
            CleanTree(app,'Secondary');
            for i = 1:length(app.Integrations.Measurements(Value).Names)
                p = uitreenode(app.Node_SecondaryStandard,'Text',char(app.Integrations.Measurements(Value).Names{i}),'NodeData',[3,i]);
            end
            collapse(app.Node_PrimaryStandard);
            expand(app.Node_SecondaryStandard);
            
            
            UpdateTimeIntegration_SecondaryStd(app);


        end
        
        
        function UpdateTimeIntegration_SecondaryStd(app)
            
            Value = app.SecondaryStd_List.Value;
            
            for i = 1:length(app.Integrations.Measurements(Value).Names)
                
                Interval = [app.Integrations.Measurements(Value).PositionsOri(i,1):1:app.Integrations.Measurements(Value).PositionsOri(i,2)];
                Filter = round(length(Interval)*(app.SecondaryStd_FilterPercentSpinner.Value/100));
                
                app.Integrations.Measurements(Value).Positions(i,1) = app.Integrations.Measurements(Value).PositionsOri(i,1)+Filter;
                app.Integrations.Measurements(Value).Positions(i,2) = app.Integrations.Measurements(Value).PositionsOri(i,2)-Filter;
                
                app.Integrations.Measurements(Value).Times(i,1:2) = [app.Data.time_DT(app.Integrations.Measurements(Value).Positions(i,1)),app.Data.time_DT(app.Integrations.Measurements(Value).Positions(i,2))];
            end
            
        end
        
        
        function CalibrateSecondaryStandardFromIt(app)
            
            
            IntStdIdx = app.SecondaryStd_IntStd.Value;
            
            Con_Std = app.Data.PrimaryStandard_ElemConc(IntStdIdx);
            Con_Unk = app.Data.SecondaryStandard_ElemConc(IntStdIdx);
            
            % Extract Itensity
            ValuePs = app.SecondaryStd_List.Value; 
            
            SignalMeas = zeros(size(app.Data.time_DT));
            for i = 1:size(app.Integrations.Measurements(ValuePs).Positions,1)
                SignalMeas(app.Integrations.Measurements(ValuePs).Positions(i,1):app.Integrations.Measurements(ValuePs).Positions(i,2)) = ones(length([app.Integrations.Measurements(ValuePs).Positions(i,1):app.Integrations.Measurements(ValuePs).Positions(i,2)]),1);
            end
            IndSignal = find(SignalMeas);
            
            % Outlier rejection
            Yi = app.Data.Cps_BackCorr(IndSignal,IntStdIdx);
            TF = isoutlier(Yi);
            IndSignal = IndSignal(find(TF == 0));
            
            It_Unk = app.Data.Cps_BackCorr(IndSignal,IntStdIdx); 
            It_Std = app.Data.Cps_PrimaryStandard(IndSignal,IntStdIdx);
            
            % Calculate k for the selected internal standard
            k = (It_Unk.*Con_Std)./(Con_Unk.*It_Std);
            k_mtx = repmat(k,1,size(app.Data.Cps_BackCorr,2));
            
            
            It_Unk_mtx = app.Data.Cps_BackCorr(IndSignal,:);
            It_Std_mtx = app.Data.Cps_PrimaryStandard(IndSignal,:);
            Con_Std_mxt = repmat(app.Data.PrimaryStandard_ElemConc,size(It_Unk_mtx,1),1);
            
            Con_Unk_mtx = (It_Unk_mtx.*Con_Std_mxt)./(k_mtx.*It_Std_mtx);
            
            Con_Unk_Median = median(Con_Unk_mtx,1);
            
            TableSecStd = {};
            for i = 1:length(Con_Unk_Median)
                TableSecStd{i,1} = app.Data.ElName{i};
                TableSecStd{i,2} = app.Data.SecondaryStandard_ElemConc(i);
                TableSecStd{i,3} = Con_Unk_Median(i);
                TableSecStd{i,4} = (app.Data.SecondaryStandard_ElemConc(i)-Con_Unk_Median(i))/app.Data.SecondaryStandard_ElemConc(i)*100;
            end
            
            app.SecondaryStd_UITable.Data = TableSecStd;
            app.SecondaryStd_UITable.Visible = 'On';
            
        end
        
        function PrepareScanData(app)
            
            Value = app.Maps_List.Value;
            
            % UPDATE TREE
            CleanTree(app,'Scans');
            for i = 1:length(app.Integrations.Measurements(Value).Names)
                p = uitreenode(app.Node_Scans,'Text',char(app.Integrations.Measurements(Value).Names{i}),'NodeData',[4,i]);
            end
            collapse(app.Node_SecondaryStandard);
            expand(app.Node_Scans);
            
            UpdateTimeIntegration_Scans(app);
            
        end
        
        function UpdateTimeIntegration_Scans(app)
            
            Value = app.Maps_List.Value;
            
            for i = 1:length(app.Integrations.Measurements(Value).Names)
                
                dt = app.Maps_TimeshiftSpinner.Value;
                
                app.Integrations.Measurements(Value).Positions(i,1) = app.Integrations.Measurements(Value).PositionsOri(i,1)+dt;
                app.Integrations.Measurements(Value).Positions(i,2) = app.Integrations.Measurements(Value).PositionsOri(i,2)+dt;
                
                app.Integrations.Measurements(Value).Times(i,1:2) = [app.Data.time_DT(app.Integrations.Measurements(Value).Positions(i,1)),app.Data.time_DT(app.Integrations.Measurements(Value).Positions(i,2))];
            end
            
        end
        
        function CalculateMaps(app)
            
            Value = app.Maps_List.Value;
            
            X1 = app.Integrations.Measurements(Value).X1;
            Y1 = app.Integrations.Measurements(Value).Y1;
            
            X2 = app.Integrations.Measurements(Value).X2;
            Y2 = app.Integrations.Measurements(Value).Y2;
            
            Slope = (Y2-Y1)./(X2-X1);
            Int = Y1-Slope.*X1;
            Distance = sqrt((X2-X1).^2+(Y2-Y1).^2);
            
            SpotSize = app.Integrations.Measurements(Value).SpotSize;
            ScanVel = app.Integrations.Measurements(Value).ScanVel;
            
            DurationReal = Distance./ScanVel; % in seconds
            NbPixels = floor(Distance./SpotSize);
            
            DtPixel = seconds(DurationReal./NbPixels);
            
            % This is to reconstruct the map
            
            Xi = [];
            Yi = [];
            TableXY(1).Xi = [];
            TableXY(1).Yi = [];
            DistanceCheck = zeros(size(Distance));
            
            CountPx = 0;
            
            for i = 1:length(X1)
                
                marker_dist = SpotSize(i);
                x = [X1(i) X2(i)];
                y = [Y1(i) Y2(i)];
                dist_from_start = [0, sqrt((x(2:end)-x(1:end-1)).^2 + (y(2:end)-y(1:end-1)).^2)];
                marker_locs = marker_dist : marker_dist : dist_from_start(end);   
                marker_indices = interp1( dist_from_start, 1 : length(dist_from_start), marker_locs);
                %marker_indices = marker_indices(1:length()-1);
                marker_base_pos = floor(marker_indices);
                
                marker_base_pos = marker_base_pos(find(marker_base_pos == 1));
                marker_indices = marker_indices(find(marker_base_pos == 1));
                
                weight_second = marker_indices - marker_base_pos;
                
                Xi_s = x(marker_base_pos) .* (1-weight_second) + x(marker_base_pos+1) .* weight_second;
                Yi_s = y(marker_base_pos) .* (1-weight_second) + y(marker_base_pos+1) .* weight_second;
                
                Xi_s = [X1(i),Xi_s];
                Yi_s = [Y1(i),Yi_s];
                
                DistanceCheck(i) = sqrt((Xi_s(end)-Xi_s(1)).^2+(Yi_s(end)-Yi_s(1)).^2);
                
                if Distance(i)-DistanceCheck(i) >= SpotSize(i)
                    Xi_s = [Xi_s,X2(i)];
                    Yi_s = [Yi_s,Y2(i)];
                    %keyboard 
                end
                
                TableXY(i).Xi = Xi_s;
                TableXY(i).Yi = Yi_s;
                
                Xi(end+1:end+length(Xi_s)) = Xi_s;
                Yi(end+1:end+length(Xi_s)) = Yi_s;
                
                % Times
                dti = DtPixel(i);
                
                Ti_j = app.Integrations.Measurements(Value).Times(i,1);
                for j = 1:length(Xi_s)
                    Ti_all = find(isbetween(app.Data.time_DT,Ti_j,Ti_j+dti));
                    Ti_j = Ti_j+dti;
                    Table_Start_t(i,j) = Ti_all(1);
                    Table_End_t(i,j) = Ti_all(end);
                    
                    CountPx = CountPx +1;
                    Vect_Start_t(CountPx) = Ti_all(1);
                    Vect_End_t(CountPx) = Ti_all(end);
                end
            end
            
            % Correct for time shift: 
            TimeShift = app.Maps_TimeshiftSpinner.Value;
            Table_Start_t = Table_Start_t+TimeShift;
            Table_End_t = Table_End_t+TimeShift;
            
            Vect_Start_t = Vect_Start_t + TimeShift;
            Vect_End_t = Vect_End_t + TimeShift;
            
            EdgesX = min(Xi)-0.5*SpotSize(1):SpotSize(1):max(Xi)+0.5*SpotSize(1);
            EdgesY = min(Yi)-0.5*SpotSize(1):SpotSize(1):max(Yi)+0.5*SpotSize(1);
            
            xbin = discretize(Xi, EdgesX);
            ybin = discretize(Yi, EdgesY); 
            
            [X_grid,Y_grid] = meshgrid([min(Xi):SpotSize(1):max(Xi)],[min(Yi):SpotSize(1):max(Yi)]);
            
            % Interpolate with gridata
            for i = 1:size(app.Data.Cps_BackCorr,2)
                
                app.Data.Cps_Maps(i).Map = zeros(size(X_grid));
                app.Data.Cps_Maps(i).Std_Map = zeros(size(X_grid));
                
                PxCompMap = zeros(size(Vect_Start_t));
                PxCompStd = zeros(size(Vect_Start_t));
                
                for j = 1:length(Vect_Start_t)
                    
                    PxCompt = app.Data.Cps_BackCorr(Vect_Start_t(j):Vect_End_t(j),i);
                    PxComptNotNaN = PxCompt(~isnan(PxCompt));
                    
                    if isempty(PxComptNotNaN)
                        PxCompMap(j) = NaN;
                    else
                        PxCompMap(j) = mean(PxComptNotNaN);
                    end
                    
                    PxCompt = app.Data.Cps_PrimaryStandard(Vect_Start_t(j):Vect_End_t(j),i);
                    PxCompStd(j) = mean(PxCompt);
                    
                    
                    %PxCompMap(j,1:length(app.Data.Cps_BackCorr(Vect_Start_t(j):Vect_End_t(j),i))) = app.Data.Cps_BackCorr(Vect_Start_t(j):Vect_End_t(j),i);
                    %PxCompStd(j,1:length(app.Data.Cps_PrimaryStandard(Vect_Start_t(j):Vect_End_t(j),i))) = app.Data.Cps_PrimaryStandard(Vect_Start_t(j):Vect_End_t(j),i);
                end
                
                Vq = griddata(Xi,Yi,PxCompMap,X_grid,Y_grid);
                Vq_Std = griddata(Xi,Yi,PxCompStd,X_grid,Y_grid);
                
                app.Data.Cps_Maps(i).Map = Vq;
                app.Data.StdMaps(i).Std_Map_Cps = Vq_Std;
                app.Data.StdMaps(i).Std_Map_Conc = app.Data.PrimaryStandard_ElemConc(i).*ones(size(Vq));
                
            end
            
            
            app.Data.MapGeometryCheck.XY_Spots = [Xi;Yi]';
            app.Data.MapGeometryCheck.Radius_Spots = repmat(SpotSize(1)/2,length(Xi),1);
            app.Data.MapGeometryCheck.X_grid = X_grid;
            app.Data.MapGeometryCheck.Y_grid = Y_grid;
            app.Data.MapGeometryCheck.xbin = xbin;
            app.Data.MapGeometryCheck.ybin = ybin;


%             figure, viscircles([Xi;Yi]',repmat(SpotSize(1)/2,length(Xi),1))
%             hold on
%             plot(X_grid,Y_grid,'sk','MarkerFaceColor','k')
%             axis image
%             figure, plot(xbin,ybin,'+')
%             axis image
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, XMapToolsApp)
            
            app.ConverterLAICPMS.Visible = 'off';
            
            app.XMapToolsApp = XMapToolsApp;
            app.LastDir = cd; %app.XMapToolsApp.XMapTools_LastDir;
            
            movegui(app.ConverterLAICPMS,"center");
            
            app.PlotDropDown.Visible = 'off';
            app.PlotDropDownLabel.Visible = 'off';
            app.TimeshiftSpinner.Visible = 'off';
            app.TimeshiftSpinnerLabel.Visible = 'off';
            app.Plot.Visible = 'off';
            
            app.BackgroundFilterPercentSpinner.Visible = 'off';
            app.BackgroundFilterPercentSpinnerLabel.Visible = 'off';
            
            app.BACKGROUNDPanel.Visible = 'off';
            app.PRIMARYSTANDARDPanel.Visible = 'off';
            app.SECONDARYSTANDARDPanel.Visible = 'off';
            app.MAPSPanel.Visible = 'off';
            
            app.PositionLabel.Visible = 'off';
            app.Position_Min.Visible = 'off';
            app.Position_Max.Visible = 'off';
            
            app.BackgroundApplyButton.Visible = 'off';
            app.Background_CorrectionList.Visible = 'off';
            
            app.CheckButton.Visible = 'off';
            app.ShowButton.Visible = 'off';
            app.ExportButton.Visible = 'off';
            app.Maps_SelElement.Visible = 'off';
            
            app.Data4Plot.List = {};
            app.Data4Plot.Data(1).Type = '';
            app.Data4Plot.Data(1).Labels = '';
            app.Data4Plot.Data(1).X = NaT(1);
            app.Data4Plot.Data(1).Y = [];


            % Load Standard data
            app.PathStdFolder = [app.XMapToolsApp.config.xmaptools.setup_path,'/Dev/Data_Std_LAICPMS'];
            
            ReadStandardData(app);
            
            app.ConverterLAICPMS.Visible = 'on';
            
        end

        % Button pushed function: LoadDatafilesButton
        function LoadDatafilesButtonPushed(app, event)
            
            % ------------------------------
            % CHEMICAL DATA: map.txt
            f=figure('Position',[1,1,5,5],'Unit','Pixel'); drawnow; f.Visible = 'off';
            cd(app.LastDir);
            [FileName,PathName,FilterIndex] = uigetfile({'*.csv','CSV-file (*.csv)'; '*.txt','TEXT-file (*.txt)'; ...
                '*.*',  'All Files (*.*)'},'Select DATA file...');
            delete(f)
            figure(app.ConverterLAICPMS);
            
            warning('off','all')
            TableData = readtable([PathName,FileName],'NumHeaderLines',3);
            warning('on','all')
            DataCps = table2array(TableData);
            DataCps = DataCps(1:end-1,2:end); % exclude time
            
            app.Data.Cps = DataCps;
            
            fid = fopen([PathName,FileName]);
            TheL = fgetl(fid); TheL = fgetl(fid); TheL = fgetl(fid);
            TheInput = fgetl(fid);
            fclose(fid);
            
            Str = textscan(TheInput,'%s','Delimiter',',');
            ElList = Str{1}(2:end);
            for i = 1:length(ElList)
                CString = char(ElList{i});
                TF = isletter(CString);
                ElListClean{i} = CString(find(TF));
            end
            
            app.Data.ElName = ElListClean;
            app.Data.ElNameOri = ElList;
            
            Str = textscan(TheL,'%s');
            Str = Str{1};
            StartingDate = [Str{3},' ',Str{4},'.000'];
            
            DT_MapStart = datetime(StartingDate,'InputFormat','yyyy-MM-dd HH:mm:ss.SSS');   % DT is for "DateTime"
            
            t =  TableData.Time_Sec_(1:end-1);
            DT_Map = seconds(t)+DT_MapStart;
            
            DT_DN = datenum(DT_Map);
            
            app.Data.tsec = t;
            app.Data.time_DT = DT_Map;   % DT for date/time
            app.Data.time_DN = DT_DN;    % DN for date number
            app.Data.dt = t(2)-t(1);
            
            SumData = sum(app.Data.Cps,2);
            app.Data.SumData = SumData;
            
            % ------------------------------
            % CHEMICAL DATA: Log File
            f=figure('Position',[1,1,5,5],'Unit','Pixel'); drawnow; f.Visible = 'off';
            cd(app.LastDir);
            [FileName_Log,PathName_Log,FilterIndex] = uigetfile({'*.csv','CSV-file (*.csv)'; '*.txt','TEXT-file (*.txt)'; ...
                '*.*',  'All Files (*.*)'},'Select LOG file...');
            delete(f)
            figure(app.ConverterLAICPMS);
            
            warning('off','all')
            app.Log.Table = readtable([PathName_Log,FileName_Log]);
            warning('on','all')
            app.Log.DT_Log = datetime(app.Log.Table.Timestamp,'InputFormat','yyyy-MM-dd HH:mm:ss.SSS');
            
            % ------------------------------
            % MATCHING
            SearchShiftAuto(app);
            
            % ------------------------------
            % PLOT CORRECTION
            Plot2CheckShift(app);
            
            app.BACKGROUNDPanel.Visible = 'on';
            
            %ExtractTimeIntegration(app);
            
        end

        % Close request function: ConverterLAICPMS
        function ConverterLAICPMSCloseRequest(app, event)
            
            figure(app.XMapToolsApp.XMapTools_GUI);
            
            delete(app)
            
        end

        % Value changed function: TimeshiftSpinner
        function TimeshiftSpinnerValueChanged(app, event)
            value = app.TimeshiftSpinner.Value;
            
            app.Log.DT_Log_Corr = app.Log.DT_Log + seconds(value);
            
            Plot2CheckShift(app);
        end

        % Button pushed function: 
        % ExtractIntegrationsBackgroundButton
        function ExtractIntegrationsBackgroundButtonPushed(app, event)
            
            app.TimeshiftSpinner.Enable = 'off';   % We can no longer change the signal
            
            app.Data4Plot.List = {'Raw_Sum'};
            app.Data4Plot.Data(1).Type = 'Raw';
            app.Data4Plot.Data(1).X = app.Data.time_DT;
            app.Data4Plot.Data(1).Y = app.Data.SumData;
            app.Data4Plot.Data(1).Background = [];
            
            app.Data4Plot.List{2} = 'Raw_All';
            app.Data4Plot.Data(2).Type = 'Raw_All';
            app.Data4Plot.Data(2).Labels = app.Data.ElName;
            app.Data4Plot.Data(2).X = app.Data.time_DT;
            app.Data4Plot.Data(2).Y = app.Data.Cps;
            app.Data4Plot.Data(2).Background = [];
            
            app.Data4Plot.List{3} = '---';
            app.Data4Plot.Data(3).Type = 'Empty';
            app.Data4Plot.Data(3).X = 0;
            app.Data4Plot.Data(3).Y = 0;
            app.Data4Plot.Data(3).Background = [];
            
            NbOri = length(app.Data4Plot.List);
            for i = 1:length(app.Data.ElName)
                app.Data4Plot.List{NbOri+i} = ['Raw_',char(app.Data.ElName{i})];
                app.Data4Plot.Data(NbOri+i).Type = 'Raw';
                app.Data4Plot.Data(NbOri+i).X = app.Data.time_DT;
                app.Data4Plot.Data(NbOri+i).Y = app.Data.Cps(:,i);
                app.Data4Plot.Data(NbOri+i).Background = [];
            end
            
            
            app.PlotDropDown.Items = app.Data4Plot.List;
            app.PlotDropDown.ItemsData = [1:length(app.Data4Plot.List)];
            app.PlotDropDown.Value = 1;
            app.PlotDropDown.Visible = 'On';
            app.PlotDropDownLabel.Visible = 'On';
            
            PlotDropDownValueChanged(app,0);
            
            ExtractTimeIntegration(app);
            
            app.Background_CorrectionList.Visible = 'On';
            app.BackgroundApplyButton.Visible = 'On';
            app.BackgroundApplyButton.Enable = 'Off';
            
            app.BackgroundFilterPercentSpinner.Visible = 'on';
            app.BackgroundFilterPercentSpinnerLabel.Visible = 'on';
            
        end

        % Value changed function: PlotDropDown
        function PlotDropDownValueChanged(app, event)
            value = app.PlotDropDown.Value;
            
            cla(app.Plot)
            
            switch app.Data4Plot.Data(value).Type
                
                case 'Empty'
                    return
                    
                    
                case 'Raw'
                    X = app.Data4Plot.Data(value).X;
                    Y = app.Data4Plot.Data(value).Y;
                    plot(app.Plot,app.Data4Plot.Data(value).X,app.Data4Plot.Data(value).Y,'-');
                    legend(app.Plot,'hide');
                    
                    if app.Background_CorrectionList.Value > 1 && isequal(app.Background_CorrectionList.Enable,'on')
                        plot(app.Plot,app.Data.time_DT,app.Data4Plot.Data(value).Background,'-r','LineWidth',2);
                    end
                    
                    
                case 'Raw_All'
                    X = app.Data4Plot.Data(value).X;
                    plot(app.Plot,app.Data4Plot.Data(value).X,app.Data4Plot.Data(value).Y,'-');
                    legend(app.Plot,app.Data4Plot.Data(value).Labels,'Location','best');
                    
                    
                case 'BC'
                    X = app.Data4Plot.Data(value).X;
                    Y = app.Data4Plot.Data(value).Y;
                    plot(app.Plot,app.Data4Plot.Data(value).X,app.Data4Plot.Data(value).Y,'-');
                    legend(app.Plot,'hide');
                    
                    
                    if app.PrimaryStd_CorrectionList.Value > 1 && isequal(app.PrimaryStd_CorrectionList.Enable,'on')
                        plot(app.Plot,app.Data.time_DT,app.Data4Plot.Data(value).Cps_PrimaryStandard,'-k','LineWidth',2);
                    end
                    
                    
                case 'BC_All'
                    X = app.Data4Plot.Data(value).X;
                    plot(app.Plot,app.Data4Plot.Data(value).X,app.Data4Plot.Data(value).Y,'-');
                    legend(app.Plot,app.Data4Plot.Data(value).Labels,'Location','best');
                    
            end
            
            hold(app.Plot,'on')
            app.Plot.YScale = 'log';
            
            rp = rulerPanInteraction('Dimensions','xy');
            app.Plot.Interactions = [rp];
            app.Plot.Visible = 'on';
            
            % HERE WE SHOULD CHECK WHAT SHOULD BE PLOTTED ON TOP
            % In a function
            
            if ~isempty(app.Tree.SelectedNodes)
                NodeData = app.Tree.SelectedNodes.NodeData;
                
                if isequal(NodeData(1),1) && NodeData(2) > 0
                    
                    Xinterval = app.Integrations.Background.Times(NodeData(2),:);
                    Yi = Y(app.Integrations.Background.Positions(NodeData(2),1):app.Integrations.Background.Positions(NodeData(2),2));
                    TF = isoutlier(Yi);
                    YValue = mean(Yi(find(TF == 0)));
                    YStd = std(Yi(find(TF == 0)));
                    
                    p=fill(app.Plot,[Xinterval(1) Xinterval(2) Xinterval(2) Xinterval(1)],[YValue+0.5*YStd YValue+0.5*YStd YValue-0.5*YStd YValue-0.5*YStd],'r');
                    p.FaceAlpha = 0.5;
                    
                    app.Position_Min.Value = app.Integrations.Background.Positions(NodeData(2),1);
                    app.Position_Max.Value = app.Integrations.Background.Positions(NodeData(2),2);
                    
                    app.PositionLabel.Visible = 'on';
                    app.Position_Min.Visible = 'on';
                    app.Position_Max.Visible = 'on';
                    
                    % Update Plot
                    DurationView = duration(app.Plot.XLim(2)-app.Plot.XLim(1));
                    DurationInter = Xinterval(2)-Xinterval(1);
                    PositionInter = Xinterval(1)+DurationInter/2;
                    
                    app.Plot.XLim = [PositionInter-DurationView/2 PositionInter+DurationView/2];
                    
                    DeltaY = app.Plot.YLim(2)-app.Plot.YLim(1);
                    app.Plot.YLim = [YValue-(DeltaY/2) YValue+(DeltaY/2)];
                    
                    return % to keep the display
                else
                    app.PositionLabel.Visible = 'off';
                    app.Position_Min.Visible = 'off';
                    app.Position_Max.Visible = 'off';
                end
                
                if isequal(NodeData(1),1) && isequal(NodeData(2),0)
                    
                    
                end
                
                if isequal(NodeData(1),2) && NodeData(2) > 0
                    
                    ValuePs = app.PrimaryStd_List.Value; 
                    
                    Xinterval = app.Integrations.Measurements(ValuePs).Times(NodeData(2),:);
                    Yi = Y(app.Integrations.Measurements(ValuePs).Positions(NodeData(2),1):app.Integrations.Measurements(ValuePs).Positions(NodeData(2),2));
                    Yi = Yi(find(~isnan(Yi)));
                    TF = isoutlier(Yi);
                    YValue = mean(Yi(find(TF == 0)));
                    YStd = std(Yi(find(TF == 0)));
                    
                    p=fill(app.Plot,[Xinterval(1) Xinterval(2) Xinterval(2) Xinterval(1)],[YValue+0.5*YStd YValue+0.5*YStd YValue-0.5*YStd YValue-0.5*YStd],'r');
                    p.FaceAlpha = 0.5;
                    
                    app.Position_Min.Value = app.Integrations.Measurements(ValuePs).Positions(NodeData(2),1);
                    app.Position_Max.Value = app.Integrations.Measurements(ValuePs).Positions(NodeData(2),2);
                    
                    app.PositionLabel.Visible = 'on';
                    app.Position_Min.Visible = 'on';
                    app.Position_Max.Visible = 'on';
                    
                    % Update Plot
                    DurationView = duration(app.Plot.XLim(2)-app.Plot.XLim(1));
                    DurationInter = Xinterval(2)-Xinterval(1);
                    PositionInter = Xinterval(1)+DurationInter/2;
                    
                    app.Plot.XLim = [PositionInter-DurationView/2 PositionInter+DurationView/2];
                    
                    DeltaY = app.Plot.YLim(2)-app.Plot.YLim(1);
                    app.Plot.YLim = [YValue-(DeltaY/2) YValue+(DeltaY/2)];
                    
                    return % to keep the display
                else
                    app.PositionLabel.Visible = 'off';
                    app.Position_Min.Visible = 'off';
                    app.Position_Max.Visible = 'off';
                end
                
                if isequal(NodeData(1),3) && NodeData(2) > 0
                    
                    ValuePs = app.SecondaryStd_List.Value; 
                    
                    Xinterval = app.Integrations.Measurements(ValuePs).Times(NodeData(2),:);
                    Yi = Y(app.Integrations.Measurements(ValuePs).Positions(NodeData(2),1):app.Integrations.Measurements(ValuePs).Positions(NodeData(2),2));
                    Yi = Yi(find(~isnan(Yi)));
                    TF = isoutlier(Yi);
                    YValue = mean(Yi(find(TF == 0)));
                    YStd = std(Yi(find(TF == 0)));
                    
                    p=fill(app.Plot,[Xinterval(1) Xinterval(2) Xinterval(2) Xinterval(1)],[YValue+0.5*YStd YValue+0.5*YStd YValue-0.5*YStd YValue-0.5*YStd],'r');
                    p.FaceAlpha = 0.5;
                    
                    app.Position_Min.Value = app.Integrations.Measurements(ValuePs).Positions(NodeData(2),1);
                    app.Position_Max.Value = app.Integrations.Measurements(ValuePs).Positions(NodeData(2),2);
                    
                    app.PositionLabel.Visible = 'on';
                    app.Position_Min.Visible = 'on';
                    app.Position_Max.Visible = 'on';
                    
                    % Update Plot
                    DurationView = duration(app.Plot.XLim(2)-app.Plot.XLim(1));
                    DurationInter = Xinterval(2)-Xinterval(1);
                    PositionInter = Xinterval(1)+DurationInter/2;
                    
                    app.Plot.XLim = [PositionInter-DurationView/2 PositionInter+DurationView/2];
                    
                    DeltaY = app.Plot.YLim(2)-app.Plot.YLim(1);
                    app.Plot.YLim = [YValue-(DeltaY/2) YValue+(DeltaY/2)];
                    
                    return % to keep the display
                else
                    app.PositionLabel.Visible = 'off';
                    app.Position_Min.Visible = 'off';
                    app.Position_Max.Visible = 'off';
                end
                
                
                if isequal(NodeData(1),4) && NodeData(2) > 0
                    
                    ValuePs = app.Maps_List.Value; 
                    
                    Xinterval = app.Integrations.Measurements(ValuePs).Times(NodeData(2),:);
                    Yi = Y(app.Integrations.Measurements(ValuePs).Positions(NodeData(2),1):app.Integrations.Measurements(ValuePs).Positions(NodeData(2),2));
                    Yi = Yi(find(~isnan(Yi)));
                    TF = isoutlier(Yi);
                    YValue = mean(Yi(find(TF == 0)));
                    YStd = std(Yi(find(TF == 0)));
                    
                    p=fill(app.Plot,[Xinterval(1) Xinterval(2) Xinterval(2) Xinterval(1)],[YValue+0.5*YStd YValue+0.5*YStd YValue-0.5*YStd YValue-0.5*YStd],'r');
                    p.FaceAlpha = 0.5;
                    
                    % Update Plot
                    DurationView = duration(app.Plot.XLim(2)-app.Plot.XLim(1));
                    DurationInter = Xinterval(2)-Xinterval(1);
                    PositionInter = Xinterval(1)+DurationInter/2;
                    
                    app.Plot.XLim = [PositionInter-DurationView/2 PositionInter+DurationView/2];
                    
                    DeltaY = app.Plot.YLim(2)-app.Plot.YLim(1);
                    app.Plot.YLim = [YValue-(DeltaY/2) YValue+(DeltaY/2)];
                    
                    return % to keep the display
                end
                
            end
            
            
 %keyboard 


        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            %selectedNodes = app.Tree.SelectedNodes;
            
            PlotDropDownValueChanged(app,0);
            
            
        end

        % Value changed function: BackgroundFilterPercentSpinner
        function BackgroundFilterPercentSpinnerValueChanged(app, event)
            %value = app.BackgroundFilterPercentSpinner.Value;
            
            UpdateTimeIntegration_Background(app);
            PlotDropDownValueChanged(app,0);
            
        end

        % Value changed function: Position_Max, Position_Min
        function Background_MinValueChanged(app, event)
            
            NodeData = app.Tree.SelectedNodes.NodeData;
            
            switch NodeData(1)
                case 1
                    Idx = NodeData(2);
                    
                    if Idx
                        app.Integrations.Background.Positions(Idx,1) = app.Position_Min.Value;
                        app.Integrations.Background.Positions(Idx,2) = app.Position_Max.Value;
                        
                        app.Integrations.Background.Times(Idx,1:2) = [app.Data.time_DT(app.Integrations.Background.Positions(Idx,1)),app.Data.time_DT(app.Integrations.Background.Positions(Idx,2))];
                        
                    end
                    PlotDropDownValueChanged(app, 0);
                    
                case 2
                    Idx = NodeData(2);
                    ValuePs = app.PrimaryStd_List.Value; 
                    
                    if Idx
                        app.Integrations.Measurements(ValuePs).Positions(Idx,1) = app.Position_Min.Value;
                        app.Integrations.Measurements(ValuePs).Positions(Idx,2) = app.Position_Max.Value;
                        
                        app.Integrations.Measurements(ValuePs).Times(Idx,1:2) = [app.Data.time_DT(app.Integrations.Measurements(ValuePs).Positions(Idx,1)),app.Data.time_DT(app.Integrations.Measurements(ValuePs).Positions(Idx,2))];
                        
                    end
                    PlotDropDownValueChanged(app, 0);
                    
                    
            end
            
            
        end

        % Value changed function: Background_CorrectionList
        function Background_CorrectionListValueChanged(app, event)
            Method = app.Background_CorrectionList.Value;
            
            BackMeas = zeros(size(app.Data.time_DT));
            for i = 1:size(app.Integrations.Background.Positions,1)
                BackMeas(app.Integrations.Background.Positions(i,1):app.Integrations.Background.Positions(i,2)) = ones(length([app.Integrations.Background.Positions(i,1):app.Integrations.Background.Positions(i,2)]),1);
            end
            IndBack = find(BackMeas);
            
            app.Data.BackgroundCorrection = zeros(size(app.Data.Cps));
            
            switch Method
                case 1 % None
                    PlotDropDownValueChanged(app, 0);
                    app.BackgroundApplyButton.Enable = 'off';
                    return
                    
                    
                case 2 % Linear
                    for i = 1:size(app.Data.Cps,2)
                        x = app.Data.time_DN(IndBack);
                        y = app.Data.Cps(IndBack,i);
                        
                        TF = isoutlier(y);
                        y = y(find(TF == 0));
                        x = x(find(TF == 0));
                        
                        warning('off','all')
                        p = polyfit(x,y,1);
                        warning('on','all')
                        Yi = polyval(p,app.Data.time_DN);
                        
                        app.Data.BackgroundCorrection(:,i) = Yi;
                        app.Data4Plot.Data(3+i).Background = Yi;
                    end
                    
                case 3 % Polynomial
                    for i = 1:size(app.Data.Cps,2)
                        x = app.Data.time_DN(IndBack);
                        y = app.Data.Cps(IndBack,i);
                        
                        TF = isoutlier(y);
                        y = y(find(TF == 0));
                        x = x(find(TF == 0));
                        
                        warning('off','all')
                        p = polyfit(x,y,2);
                        warning('on','all')
                        Yi = polyval(p,app.Data.time_DN);
                        
                        app.Data.BackgroundCorrection(:,i) = Yi;
                        app.Data4Plot.Data(3+i).Background = Yi;
                    end
                    
                    
                case 4 % Step function
                    for i = 1:size(app.Data.Cps,2)
                        Yi = zeros(size(app.Data.time_DN));
                        for j = 1:size(app.Integrations.Background.Positions,1)
                            ValY = app.Data.Cps(app.Integrations.Background.Positions(j,1):app.Integrations.Background.Positions(j,2),i);
                            TF = isoutlier(ValY);
                            ValY = ValY(find(TF == 0));
                            MeanValue = mean(ValY);
                            if isequal(j,1)
                                Yi(1:app.Integrations.Background.Positions(j+1,1)-1) = MeanValue.*ones(size(Yi(1:app.Integrations.Background.Positions(j+1,1)-1)));
                            elseif j<size(app.Integrations.Background.Positions,2)
                                Yi(app.Integrations.Background.Positions(j,1):app.Integrations.Background.Positions(j+1,1)-1) = MeanValue.*ones(size(Yi(PreviousPos+1:app.Integrations.Background.Positions(j+1,1)-1)));
                            else
                                Yi(app.Integrations.Background.Positions(j,1):end) = MeanValue.*ones(size(Yi(app.Integrations.Background.Positions(j,1):end)));
                            end
                        end
                        
                        app.Data.BackgroundCorrection(:,i) = Yi;
                        app.Data4Plot.Data(3+i).Background = Yi;
                    end
                    
                case 5 % Spline
                    for i = 1:size(app.Data.Cps,2)
%                         x = app.Data.time_DN(IndBack);
%                         y = app.Data.Cps(IndBack,i);
%                         
%                         TF = isoutlier(y);
%                         y = y(find(TF == 0));
%                         x = x(find(TF == 0));

                        Yi_t = zeros(size(app.Data.time_DN));
                        for j = 1:size(app.Integrations.Background.Positions,1)
                            ValY = app.Data.Cps(app.Integrations.Background.Positions(j,1):app.Integrations.Background.Positions(j,2),i);
                            TF = isoutlier(ValY);
                            ValY = ValY(find(TF == 0));
                            MeanValue = mean(ValY);
                            if isequal(MeanValue,0)
                                MeanValue = 1;
                            end
                            Yi_t(app.Integrations.Background.Positions(j,1)) = MeanValue;
                            Yi_t(app.Integrations.Background.Positions(j,2)) = MeanValue;
                        end
                        
                        IndBackSpec = find(Yi_t > 0);

                        x = app.Data.time_DN(IndBackSpec);
                        y = Yi_t(IndBackSpec);
                        
                        warning('off','all')
                        Yi = spline(x,y,app.Data.time_DN);
                        warning('on','all')
                        
                        app.Data.BackgroundCorrection(:,i) = Yi;
                        app.Data4Plot.Data(3+i).Background = Yi;
                    end


            end
            
            app.Data4Plot.Data(1).Background = sum(app.Data.BackgroundCorrection,2);
            
            % Plot the correction
            PlotDropDownValueChanged(app, 0);
            
            
            app.BackgroundApplyButton.Enable = 'on';


        end

        % Button pushed function: BackgroundApplyButton
        function BackgroundApplyButtonPushed(app, event)
            
            app.ExtractIntegrationsBackgroundButton.Enable = 'off';
            app.BackgroundFilterPercentSpinnerLabel.Enable = 'off';
            app.BackgroundFilterPercentSpinner.Enable = 'off';
            app.Background_CorrectionList.Enable = 'off';
            app.BackgroundApplyButton.Enable = 'off';
            
            
            % Generate corrected signal
            app.Data.Cps_BackCorr = app.Data.Cps - app.Data.BackgroundCorrection;
            
            WereNeg = find(app.Data.Cps_BackCorr <= 0);
            app.Data.Cps_BackCorr(WereNeg) = nan(size(WereNeg));
            
            app.Data.SumData_BackCorr = sum(app.Data.Cps_BackCorr,2);
            
            Pos = length(app.Data4Plot.List)+1;
            PosNew = Pos;
            
            app.Data4Plot.List{Pos} = '---';
            app.Data4Plot.Data(Pos).Type = 'Empty';
            app.Data4Plot.Data(Pos).X = 0;
            app.Data4Plot.Data(Pos).Y = 0;
            app.Data4Plot.Data(Pos).Background = [];
            
            Pos = Pos+1;
            app.Data4Plot.List{Pos} = 'BackCorr_Sum';
            app.Data4Plot.Data(Pos).Type = 'BC';
            app.Data4Plot.Data(Pos).X = app.Data.time_DT;
            app.Data4Plot.Data(Pos).Y = app.Data.SumData_BackCorr;
            app.Data4Plot.Data(Pos).Background = [];
            
            Pos = Pos+1;
            app.Data4Plot.List{Pos} = 'BackCorr_All';
            app.Data4Plot.Data(Pos).Type = 'BC_All';
            app.Data4Plot.Data(Pos).Labels = app.Data.ElName;
            app.Data4Plot.Data(Pos).X = app.Data.time_DT;
            app.Data4Plot.Data(Pos).Y = app.Data.Cps_BackCorr;
            app.Data4Plot.Data(Pos).Background = [];
            
            Pos = Pos+1;
            app.Data4Plot.List{Pos} = '---';
            app.Data4Plot.Data(Pos).Type = 'Empty';
            app.Data4Plot.Data(Pos).X = 0;
            app.Data4Plot.Data(Pos).Y = 0;
            app.Data4Plot.Data(Pos).Background = [];
            
            app.Start_Idx_Data4Plot_BackgroundCorrected = Pos;   % work for +i
            
            for i = 1:length(app.Data.ElName)
                app.Data4Plot.List{Pos+i} = ['BackCorr_',char(app.Data.ElName{i})];
                app.Data4Plot.Data(Pos+i).Type = 'BC';
                app.Data4Plot.Data(Pos+i).X = app.Data.time_DT;
                app.Data4Plot.Data(Pos+i).Y = app.Data.Cps_BackCorr(:,i);
                app.Data4Plot.Data(Pos+i).Background = [];
            end
            
            app.PlotDropDown.Items = app.Data4Plot.List;
            app.PlotDropDown.ItemsData = [1:length(app.Data4Plot.List)];
            app.PlotDropDown.Value = PosNew+1;
            
            PlotDropDownValueChanged(app,0);
            
             
            app.PRIMARYSTANDARDPanel.Visible = 'on';
            
            app.PrimaryStd_List.Items = app.Integrations.TypeNames;
            app.PrimaryStd_List.ItemsData = [1:length(app.Integrations.TypeNames)];
            app.PrimaryStd_List.Value = 1;
            
            app.SecondaryStd_List.Items = app.Integrations.TypeNames;
            app.SecondaryStd_List.ItemsData = [1:length(app.Integrations.TypeNames)];
            app.SecondaryStd_List.Value = 2;
            
            app.Maps_List.Items = app.Integrations.TypeNames;
            app.Maps_List.ItemsData = [1:length(app.Integrations.TypeNames)];
            app.Maps_List.Value = 3;
            
            app.PrimaryStd_ApplyButton.Enable = 'off';
            
            
            collapse(app.Node_Background);
            
            PrimaryStd_ListValueChanged(app,0);
            
        end

        % Value changed function: PrimaryStd_CorrectionList
        function PrimaryStd_CorrectionListValueChanged(app, event)
            Method = app.PrimaryStd_CorrectionList.Value;
            
            ValuePs = app.PrimaryStd_List.Value; 
            
            SignalMeas = zeros(size(app.Data.time_DT));
            for i = 1:size(app.Integrations.Measurements(ValuePs).Positions,1)
                SignalMeas(app.Integrations.Measurements(ValuePs).Positions(i,1):app.Integrations.Measurements(ValuePs).Positions(i,2)) = ones(length([app.Integrations.Measurements(ValuePs).Positions(i,1):app.Integrations.Measurements(ValuePs).Positions(i,2)]),1);
            end
            IndSignal = find(SignalMeas);
            
            app.Data.PrimaryStandard = zeros(size(app.Data.Cps));
            
            switch Method
                case 1 % None
                    PlotDropDownValueChanged(app, 0);
                    app.PrimaryStd_ApplyButton.Enable = 'off';
                    return
                    
                    
                case 2 % Linear   
                    for i = 1:size(app.Data.Cps_BackCorr,2)
                        x = app.Data.time_DN(IndSignal);
                        y = app.Data.Cps_BackCorr(IndSignal,i);
                        
                        IndNotNan = find(~isnan(y));
                        x = x(IndNotNan);
                        y = y(IndNotNan);
                        
                        TF = isoutlier(y);
                        y = y(find(TF == 0));
                        x = x(find(TF == 0));
                        
                        warning('off','all')
                        p = polyfit(x,y,1);
                        warning('on','all')
                        Yi = polyval(p,app.Data.time_DN);
                        
                        app.Data.Cps_PrimaryStandard(:,i) = Yi;
                        app.Data4Plot.Data(app.Start_Idx_Data4Plot_BackgroundCorrected+i).Cps_PrimaryStandard = Yi;
                        
                    end
                    
                case 3 % Polynomial
                    for i = 1:size(app.Data.Cps_BackCorr,2)
                        x = app.Data.time_DN(IndSignal);
                        y = app.Data.Cps_BackCorr(IndSignal,i);
                        
                        IndNotNan = find(~isnan(y));
                        x = x(IndNotNan);
                        y = y(IndNotNan);
                        
                        TF = isoutlier(y);
                        y = y(find(TF == 0));
                        x = x(find(TF == 0));
                        
                        warning('off','all')
                        p = polyfit(x,y,2);
                        warning('on','all')
                        Yi = polyval(p,app.Data.time_DN);
                        
                        app.Data.Cps_PrimaryStandard(:,i) = Yi;
                        app.Data4Plot.Data(app.Start_Idx_Data4Plot_BackgroundCorrected+i).Cps_PrimaryStandard = Yi;
                    end
                    
                    
                case 4 % Step function
                    for i = 1:size(app.Data.Cps_BackCorr,2)
                        Yi = zeros(size(app.Data.time_DN));
                        for j = 1:size(app.Integrations.Measurements(ValuePs).Positions,1)
                            ValY = app.Data.Cps_BackCorr(app.Integrations.Measurements(ValuePs).Positions(j,1):app.Integrations.Measurements(ValuePs).Positions(j,2),i);
                            IndNotNan = find(~isnan(ValY));
                            ValY = ValY(IndNotNan);
                            TF = isoutlier(ValY);
                            ValY = ValY(find(TF == 0));
                            MeanValue = mean(ValY);
                            if isequal(j,1)
                                Yi(1:app.Integrations.Measurements(ValuePs).Positions(j+1,1)-1) = MeanValue.*ones(size(Yi(1:app.Integrations.Measurements(ValuePs).Positions(j+1,1)-1)));
                            elseif j<size(app.Integrations.Measurements(ValuePs).Positions,2)
                                Yi(app.Integrations.Measurements(ValuePs).Positions(j,1):app.Integrations.Measurements(ValuePs).Positions(j+1,1)-1) = MeanValue.*ones(size(Yi(PreviousPos+1:app.Integrations.Measurements(ValuePs).Positions(j+1,1)-1)));
                            else
                                Yi(app.Integrations.Measurements(ValuePs).Positions(j,1):end) = MeanValue.*ones(size(Yi(app.Integrations.Measurements(ValuePs).Positions(j,1):end)));
                            end
                        end
                        
                        app.Data.Cps_PrimaryStandard(:,i) = Yi;
                        app.Data4Plot.Data(app.Start_Idx_Data4Plot_BackgroundCorrected+i).Cps_PrimaryStandard = Yi;
                    end
                    
                case 5 % Spline
                    for i = 1:size(app.Data.Cps,2)
                        Yi_t = zeros(size(app.Data.time_DN));
                        for j = 1:size(app.Integrations.Measurements(ValuePs).Positions,1)
                            ValY = app.Data.Cps_BackCorr(app.Integrations.Measurements(ValuePs).Positions(j,1):app.Integrations.Measurements(ValuePs).Positions(j,2),i);
                            IndNotNan = find(~isnan(ValY));
                            ValY = ValY(IndNotNan);
                            TF = isoutlier(ValY);
                            ValY = ValY(find(TF == 0));
                            MeanValue = mean(ValY);
                            if isequal(MeanValue,0)
                                MeanValue = 1;    % not sure if this judicious here
                            end
                            Yi_t(app.Integrations.Measurements(ValuePs).Positions(j,1)) = MeanValue;
                            Yi_t(app.Integrations.Measurements(ValuePs).Positions(j,2)) = MeanValue;
                        end
                        
                        IndMeasurementSpec = find(Yi_t > 0);

                        x = app.Data.time_DN(IndMeasurementSpec);
                        y = Yi_t(IndMeasurementSpec);
                        
                        warning('off','all')
                        Yi = spline(x,y,app.Data.time_DN);
                        warning('on','all')
                        
                        app.Data.Cps_PrimaryStandard(:,i) = Yi;
                        app.Data4Plot.Data(app.Start_Idx_Data4Plot_BackgroundCorrected+i).Cps_PrimaryStandard = Yi;
                    end


            end
            
            app.Data4Plot.Data(app.Start_Idx_Data4Plot_BackgroundCorrected-2).Cps_PrimaryStandard = sum(app.Data.Cps_PrimaryStandard,2);
            
            % Plot the correction
            PlotDropDownValueChanged(app, 0);
            
            
            app.PrimaryStd_ApplyButton.Enable = 'on';


        end

        % Value changed function: PrimaryStd_List
        function PrimaryStd_ListValueChanged(app, event)
            Value = app.PrimaryStd_List.Value;
            
            % UPDATE TREE
            CleanTree(app,'Primary');
            for i = 1:length(app.Integrations.Measurements(Value).Names)
                p = uitreenode(app.Node_PrimaryStandard,'Text',char(app.Integrations.Measurements(Value).Names{i}),'NodeData',[2,i]);
            end
            expand(app.Node_PrimaryStandard);
            
            UpdateTimeIntegration_PrimaryStd(app);
            
            
        end

        % Value changed function: PrimaryStd_FilterPercentSpinner
        function PrimaryStd_FilterPercentSpinnerValueChanged(app, event)
            UpdateTimeIntegration_PrimaryStd(app);
            PlotDropDownValueChanged(app,0);
        end

        % Button pushed function: PrimaryStd_ApplyButton
        function PrimaryStd_ApplyButtonPushed(app, event)
            
            
            Std = app.PrimaryStd_List.Items{app.PrimaryStd_List.Value};
            
            [IsStd] = ismember(app.RefData_LAICPMS.StdNames,upper(Std));
            
            if ~isequal(sum(IsStd),1)
                errordlg('Standart not found, Pierre needs to work more here...','XMapTools');
                % Select the corresponding Std in the list...
                return
            end
            
            WhereStd = find(IsStd);
            
            ElListStd = app.RefData_LAICPMS.Data(WhereStd).Elem;
            ElAnalysis = app.Data.ElName;
            
            [IsElem,ElemIdx] = ismember(ElAnalysis,ElListStd);
            
            if ~isequal(sum(IsElem),length(IsElem))
                errordlg('Element in Standart not found, Pierre needs to work more here...','XMapTools');
                return
            end
            
            app.Data.PrimaryStandard_ElemConc = app.RefData_LAICPMS.Data(WhereStd).Value(ElemIdx);
            
            app.PrimaryStd_CorrectionList.Enable = 'off';
            app.PrimaryStd_List.Enable = 'off';
            app.PrimaryStd_FilterPercentSpinner.Enable = 'off';
            app.PrimaryStd_FilterPercentSpinnerLabel.Enable = 'off';
            app.PrimaryStd_ApplyButton.Enable = 'off';
            
            app.SECONDARYSTANDARDPanel.Visible = 'on';
            app.SecondaryStd_UITable.Visible = 'off';
            
            PrepareSecondaryStandardData(app);
            
            SecondaryStd_ListValueChanged(app, event);
        end

        % Value changed function: SecondaryStd_IntStd
        function SecondaryStd_IntStdValueChanged(app, event)
            
            CalibrateSecondaryStandardFromIt(app);
            app.SecondaryStd_ApplyButton.Enable = 'on';
            
            %keyboard
        end

        % Value changed function: SecondaryStd_FilterPercentSpinner
        function SecondaryStd_FilterPercentSpinnerValueChanged(app, event)
            UpdateTimeIntegration_SecondaryStd(app);
            PlotDropDownValueChanged(app,0);
        end

        % Value changed function: SecondaryStd_List
        function SecondaryStd_ListValueChanged(app, event)
            
            PrepareSecondaryStandardData(app);
            
            app.SecondaryStd_ApplyButton.Enable = 'off';
            
        end

        % Button pushed function: SecondaryStd_ApplyButton
        function SecondaryStd_ApplyButtonPushed(app, event)
            
            app.SecondaryStd_ApplyButton.Enable = 'off';
            app.SecondaryStd_IntStd.Enable = 'off';
            app.SecondaryStd_IntStdLabel.Enable = 'off';
            
            app.SecondaryStd_UITable.Enable = 'off';
            app.SecondaryStd_FilterPercentSpinner.Enable = 'off';
            app.SecondaryStd_FilterPercentSpinnerLabel.Enable = 'off';
            app.SecondaryStd_List.Enable = 'off';
            
            collapse(app.Node_SecondaryStandard);
            
            app.MAPSPanel.Visible = 'on';
            
            PrepareScanData(app);
            
        end

        % Menu selected function: CopyMenu
        function CopyMenuSelected(app, event)
            
            
            d = [app.SecondaryStd_UITable.Data];
            
            str = '';
            for i = 1:size(d,1)
                str = sprintf('%s\n%s\t%f\t%f\t%f',str,char(d{i,1}),d{i,2},d{i,3},d{i,4});
            end
            
            clipboard ('copy',str);
            
        end

        % Button pushed function: Maps_ApplyButton
        function Maps_ApplyButtonPushed(app, event)
            
            CalculateMaps(app);
            
            app.CheckButton.Visible = 'On';
            app.ShowButton.Visible = 'On';
            app.ExportButton.Visible = 'On';
            app.Maps_SelElement.Visible = 'On';
            
            app.Maps_SelElement.Items = app.Data.ElName;
            app.Maps_SelElement.ItemsData = [1:length(app.Data.ElName)];
            
            app.Maps_ApplyButton.Enable = 'Off';
            app.Maps_TimeshiftSpinnerLabel.Enable = 'Off';
            app.Maps_TimeshiftSpinner.Enable = 'Off';
            app.Maps_List.Enable = 'Off';
            
        end

        % Value changed function: Maps_List
        function Maps_ListValueChanged(app, event)
            
            PrepareScanData(app);
            
        end

        % Value changed function: Maps_TimeshiftSpinner
        function Maps_TimeshiftSpinnerValueChanged(app, event)
            UpdateTimeIntegration_Scans(app);
            PlotDropDownValueChanged(app,0);
        end

        % Button pushed function: CheckButton
        function CheckButtonPushed(app, event)
            figure, viscircles(app.Data.MapGeometryCheck.XY_Spots,app.Data.MapGeometryCheck.Radius_Spots)
            hold on
            plot(app.Data.MapGeometryCheck.X_grid,app.Data.MapGeometryCheck.Y_grid,'sk','MarkerFaceColor','k')
            axis image
            figure, plot(app.Data.MapGeometryCheck.xbin,app.Data.MapGeometryCheck.ybin,'+')
            axis image
        end

        % Button pushed function: ShowButton
        function ShowButtonPushed(app, event)
           
            Value = app.Maps_SelElement.Value;
            
            figure(999), 
            subplot(2,2,1)
            imagesc(app.Data.Cps_Maps(Value).Map), axis image, set(gca,'ColorScale','log'), colorbar
            subplot(2,2,2)
            imagesc(app.Data.StdMaps(Value).Std_Map_Cps), axis image, colorbar
            subplot(2,2,4)
            imagesc(app.Data.StdMaps(Value).Std_Map_Conc), axis image, colorbar
            
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            
            [Success,Message,MessageID] = mkdir('Maps_cps');
            
            cd Maps_cps/
            for i = 1:length(app.Data.Cps_Maps)
                TheMap = app.Data.Cps_Maps(i).Map;
                save([char(app.Data.ElName{i}),'.txt'],'TheMap','-ascii');
                
                MapStandards_Import.StdMaps = app.Data.StdMaps;
                MapStandards_Import.ElName = app.Data.ElName;
                
                save('MapStandards_Import.mat','MapStandards_Import');
            end
            cd ..
            
            close(app.ConverterLAICPMS);
            
            
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            
            if isempty(app.XMapToolsApp.Id_HelpTool)
                Help_Display(app.XMapToolsApp,'XMT_help_Converter_LAICPMS.html');
            else
                app.XMapToolsApp.Id_HelpTool.UpdateTextHelp('XMT_help_Converter_LAICPMS.html');
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ConverterLAICPMS = uifigure('Visible', 'off');
            app.ConverterLAICPMS.Name = 'Converter For LA-ICPMS Data – XMapTools';
            app.ConverterLAICPMS.CloseRequestFcn = createCallbackFcn(app, @ConverterLAICPMSCloseRequest, true);
            app.GridLayout = uigridlayout(app.ConverterLAICPMS);
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = 'logo_xmap_final.png';
            app.Tree = uitree(app.GridLayout);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Node_Background = uitreenode(app.Tree);
            app.Node_Background.NodeData = [1 0];
            app.Node_Background.Icon = 'XXX_Background.png';
            app.Node_Background.Text = 'Background';
            app.Node_PrimaryStandard = uitreenode(app.Tree);
            app.Node_PrimaryStandard.NodeData = [2 0];
            app.Node_PrimaryStandard.Icon = 'XXX_PrimaryStd.png';
            app.Node_PrimaryStandard.Text = 'Primary Standard';
            app.Node_SecondaryStandard = uitreenode(app.Tree);
            app.Node_SecondaryStandard.NodeData = [3 0];
            app.Node_SecondaryStandard.Icon = 'XXX_SecondaryStd.png';
            app.Node_SecondaryStandard.Text = 'Secondary Standard';
            app.Node_Scans = uitreenode(app.Tree);
            app.Node_Scans.NodeData = [4 0];
            app.Node_Scans.Icon = 'XXX_Scans.png';
            app.Node_Scans.Text = 'Scans';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.LoadDatafilesButton = uibutton(app.GridLayout2, 'push');
            app.LoadDatafilesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDatafilesButtonPushed, true);
            app.LoadDatafilesButton.Icon = '323-add.png';
            app.LoadDatafilesButton.Text = ' Load Datafiles';
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.HelpButton = uibutton(app.GridLayout4, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Icon = '061-info.png';
            app.HelpButton.Text = '';
            app.TimeshiftSpinnerLabel = uilabel(app.GridLayout2);
            app.TimeshiftSpinnerLabel.Text = 'Time shift';
            app.TimeshiftSpinner = uispinner(app.GridLayout2);
            app.TimeshiftSpinner.Step = 0.1;
            app.TimeshiftSpinner.ValueDisplayFormat = '%.1f';
            app.TimeshiftSpinner.ValueChangedFcn = createCallbackFcn(app, @TimeshiftSpinnerValueChanged, true);
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.PlotDropDownLabel = uilabel(app.GridLayout3);
            app.PlotDropDownLabel.Text = 'Plot';
            app.PlotDropDown = uidropdown(app.GridLayout3);
            app.PlotDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotDropDownValueChanged, true);
            app.BACKGROUNDPanel = uipanel(app.GridLayout);
            app.BACKGROUNDPanel.Title = 'BACKGROUND';
            app.GridLayout5 = uigridlayout(app.BACKGROUNDPanel);
            app.ExtractIntegrationsBackgroundButton = uibutton(app.GridLayout5, 'push');
            app.ExtractIntegrationsBackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractIntegrationsBackgroundButtonPushed, true);
            app.ExtractIntegrationsBackgroundButton.Text = 'Extract Integrations';
            app.BackgroundFilterPercentSpinnerLabel = uilabel(app.GridLayout5);
            app.BackgroundFilterPercentSpinnerLabel.Text = 'Filter (%)';
            app.BackgroundFilterPercentSpinner = uispinner(app.GridLayout5);
            app.BackgroundFilterPercentSpinner.Limits = [0 49];
            app.BackgroundFilterPercentSpinner.ValueChangedFcn = createCallbackFcn(app, @BackgroundFilterPercentSpinnerValueChanged, true);
            app.BackgroundFilterPercentSpinner.Value = 10;
            app.Background_CorrectionList = uidropdown(app.GridLayout5);
            app.Background_CorrectionList.Items = {'None', 'Linear', 'Polynomial', 'Step function', 'Spline'};
            app.Background_CorrectionList.ItemsData = [1 2 3 4 5];
            app.Background_CorrectionList.ValueChangedFcn = createCallbackFcn(app, @Background_CorrectionListValueChanged, true);
            app.Background_CorrectionList.Value = 1;
            app.BackgroundApplyButton = uibutton(app.GridLayout5, 'push');
            app.BackgroundApplyButton.ButtonPushedFcn = createCallbackFcn(app, @BackgroundApplyButtonPushed, true);
            app.BackgroundApplyButton.Text = 'Apply';
            app.PRIMARYSTANDARDPanel = uipanel(app.GridLayout);
            app.PRIMARYSTANDARDPanel.Title = 'PRIMARY STANDARD';
            app.GridLayout6 = uigridlayout(app.PRIMARYSTANDARDPanel);
            app.PrimaryStd_FilterPercentSpinnerLabel = uilabel(app.GridLayout6);
            app.PrimaryStd_FilterPercentSpinnerLabel.Text = 'Filter (%)';
            app.PrimaryStd_FilterPercentSpinner = uispinner(app.GridLayout6);
            app.PrimaryStd_FilterPercentSpinner.Limits = [0 49];
            app.PrimaryStd_FilterPercentSpinner.ValueChangedFcn = createCallbackFcn(app, @PrimaryStd_FilterPercentSpinnerValueChanged, true);
            app.PrimaryStd_FilterPercentSpinner.Value = 10;
            app.PrimaryStd_List = uidropdown(app.GridLayout6);
            app.PrimaryStd_List.Items = {'None', ''};
            app.PrimaryStd_List.ItemsData = 1;
            app.PrimaryStd_List.ValueChangedFcn = createCallbackFcn(app, @PrimaryStd_ListValueChanged, true);
            app.PrimaryStd_List.Value = 1;
            app.PrimaryStd_ApplyButton = uibutton(app.GridLayout6, 'push');
            app.PrimaryStd_ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @PrimaryStd_ApplyButtonPushed, true);
            app.PrimaryStd_ApplyButton.Text = 'Apply';
            app.PrimaryStd_CorrectionList = uidropdown(app.GridLayout6);
            app.PrimaryStd_CorrectionList.Items = {'None', 'Linear', 'Polynomial', 'Step function', 'Spline'};
            app.PrimaryStd_CorrectionList.ItemsData = [1 2 3 4 5];
            app.PrimaryStd_CorrectionList.ValueChangedFcn = createCallbackFcn(app, @PrimaryStd_CorrectionListValueChanged, true);
            app.PrimaryStd_CorrectionList.Value = 1;
            app.SECONDARYSTANDARDPanel = uipanel(app.GridLayout);
            app.SECONDARYSTANDARDPanel.Title = 'SECONDARY STANDARD';
            app.GridLayout7 = uigridlayout(app.SECONDARYSTANDARDPanel);
            app.SecondaryStd_List = uidropdown(app.GridLayout7);
            app.SecondaryStd_List.Items = {'None'};
            app.SecondaryStd_List.ItemsData = 1;
            app.SecondaryStd_List.ValueChangedFcn = createCallbackFcn(app, @SecondaryStd_ListValueChanged, true);
            app.SecondaryStd_List.Value = 1;
            app.SecondaryStd_FilterPercentSpinnerLabel = uilabel(app.GridLayout7);
            app.SecondaryStd_FilterPercentSpinnerLabel.Text = 'Filter (%)';
            app.SecondaryStd_FilterPercentSpinner = uispinner(app.GridLayout7);
            app.SecondaryStd_FilterPercentSpinner.Limits = [0 49];
            app.SecondaryStd_FilterPercentSpinner.ValueChangedFcn = createCallbackFcn(app, @SecondaryStd_FilterPercentSpinnerValueChanged, true);
            app.SecondaryStd_FilterPercentSpinner.Value = 10;
            app.SecondaryStd_UITable = uitable(app.GridLayout7);
            app.SecondaryStd_UITable.ColumnName = {'El'; 'Ref (ppm)'; 'Calc (ppm)'; 'Delta (%)'};
            app.SecondaryStd_UITable.RowName = {};
            app.SecondaryStd_IntStdLabel = uilabel(app.GridLayout7);
            app.SecondaryStd_IntStdLabel.Text = 'Int. Std:';
            app.SecondaryStd_IntStd = uidropdown(app.GridLayout7);
            app.SecondaryStd_IntStd.ValueChangedFcn = createCallbackFcn(app, @SecondaryStd_IntStdValueChanged, true);
            app.SecondaryStd_ApplyButton = uibutton(app.GridLayout7, 'push');
            app.SecondaryStd_ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @SecondaryStd_ApplyButtonPushed, true);
            app.SecondaryStd_ApplyButton.Text = 'Apply';
            app.MAPSPanel = uipanel(app.GridLayout);
            app.MAPSPanel.Title = 'MAPS (SCANS)';
            app.GridLayout8 = uigridlayout(app.MAPSPanel);
            app.Maps_ApplyButton = uibutton(app.GridLayout8, 'push');
            app.Maps_ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @Maps_ApplyButtonPushed, true);
            app.Maps_ApplyButton.Text = 'Apply';
            app.Maps_TimeshiftSpinnerLabel = uilabel(app.GridLayout8);
            app.Maps_TimeshiftSpinnerLabel.Text = 'Cycle shift';
            app.Maps_TimeshiftSpinner = uispinner(app.GridLayout8);
            app.Maps_TimeshiftSpinner.Limits = [0 Inf];
            app.Maps_TimeshiftSpinner.RoundFractionalValues = 'on';
            app.Maps_TimeshiftSpinner.ValueDisplayFormat = '%.0f';
            app.Maps_TimeshiftSpinner.ValueChangedFcn = createCallbackFcn(app, @Maps_TimeshiftSpinnerValueChanged, true);
            app.Maps_List = uidropdown(app.GridLayout8);
            app.Maps_List.Items = {'None'};
            app.Maps_List.ItemsData = 1;
            app.Maps_List.ValueChangedFcn = createCallbackFcn(app, @Maps_ListValueChanged, true);
            app.Maps_List.Value = 1;
            app.CheckButton = uibutton(app.GridLayout8, 'push');
            app.CheckButton.ButtonPushedFcn = createCallbackFcn(app, @CheckButtonPushed, true);
            app.CheckButton.Icon = '091-share.png';
            app.CheckButton.Text = 'Check';
            app.ShowButton = uibutton(app.GridLayout8, 'push');
            app.ShowButton.ButtonPushedFcn = createCallbackFcn(app, @ShowButtonPushed, true);
            app.ShowButton.Icon = '004-picture.png';
            app.ShowButton.Text = 'Show';
            app.ExportButton = uibutton(app.GridLayout8, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Icon = '311-app.png';
            app.ExportButton.Text = 'Export';
            app.Maps_SelElement = uidropdown(app.GridLayout8);
            app.GridLayout9 = uigridlayout(app.GridLayout);
            app.PositionLabel = uilabel(app.GridLayout9);
            app.PositionLabel.Text = 'Position';
            app.Position_Min = uispinner(app.GridLayout9);
            app.Position_Min.ValueChangedFcn = createCallbackFcn(app, @Background_MinValueChanged, true);
            app.Position_Max = uispinner(app.GridLayout9);
            app.Position_Max.ValueChangedFcn = createCallbackFcn(app, @Background_MinValueChanged, true);
            app.IntegrationMenuLabel = uilabel(app.GridLayout);
            app.IntegrationMenuLabel.Text = 'Integration Menu';
            app.Plot = uiaxes(app.GridLayout);
            xlabel(app.Plot, 'Time')
            ylabel(app.Plot, 'Intensity')
            app.ContextMenu = uicontextmenu(app.ConverterLAICPMS);
            % Assign app.ContextMenu
            app.SecondaryStd_UITable.ContextMenu = app.ContextMenu;
            app.CopyMenu = uimenu(app.ContextMenu);
            app.CopyMenu.MenuSelectedFcn = createCallbackFcn(app, @CopyMenuSelected, true);
            app.CopyMenu.Text = 'Copy';
            % Show the figure after all components are created
            app.ConverterLAICPMS.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Converter_LAICPMS(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ConverterLAICPMS)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ConverterLAICPMS)
        end
    end
end